classdef MainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        SaveMPCMenu                   matlab.ui.container.Menu
        LoadMPCMenu                   matlab.ui.container.Menu
        ResetGUIMenu                  matlab.ui.container.Menu
        InputPanel                    matlab.ui.container.Panel
        DefinesystemmodelPanel        matlab.ui.container.Panel
        SystemModelButton             matlab.ui.control.Button
        SystemModelLamp               matlab.ui.control.Lamp
        AddcontraintsPanel            matlab.ui.container.Panel
        ConstraintsButton             matlab.ui.control.Button
        ConstraintsLamp               matlab.ui.control.Lamp
        DefinepenaltiesPanel          matlab.ui.container.Panel
        PenaltiesButton               matlab.ui.control.Button
        PenaltiesLamp                 matlab.ui.control.Lamp
        TSetPenaltyButtonGroup        matlab.ui.container.ButtonGroup
        NotsetandtpenaltyButton       matlab.ui.control.RadioButton
        ComputetsetandtpenaltyButton  matlab.ui.control.RadioButton
        TSetPenaltyLamp               matlab.ui.control.Lamp
        ChoosepredictionhorizonPanel  matlab.ui.container.Panel
        HorizonEditField              matlab.ui.control.NumericEditField
        HorizonLamp                   matlab.ui.control.Lamp
        SummaryPanel                  matlab.ui.container.Panel
        PenaltiesLabel                matlab.ui.control.Label
        PenaltiesLabel2               matlab.ui.control.Label
        NumberofconstraintsinputsstatesLabel  matlab.ui.control.Label
        ConstraintsLabel              matlab.ui.control.Label
        NuNxLabel                     matlab.ui.control.Label
        NumberofinputsstatesLabel     matlab.ui.control.Label
        TerminalsetpenaltyLabel       matlab.ui.control.Label
        TerminalLabel                 matlab.ui.control.Label
        HorizonLabel                  matlab.ui.control.Label
        PredictionhorizonLabel        matlab.ui.control.Label
        SimulationPanel               matlab.ui.container.Panel
        SelectsynthesisproblemPanel   matlab.ui.container.Panel
        GridLayoutProblem             matlab.ui.container.GridLayout
        ProblemSwitch                 matlab.ui.control.Switch
        ReferenceTrajectoryButton     matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        OpenloopsimulationTab         matlab.ui.container.Tab
        OLTable                       matlab.ui.control.Table
        EntertheinitialstateLabel     matlab.ui.control.Label
        OLSimulateButton              matlab.ui.control.Button
        OLNewSimulationButton         matlab.ui.control.Button
        OLUDropDown                   matlab.ui.control.DropDown
        OLXDropDown                   matlab.ui.control.DropDown
        OLStepSpinner                 matlab.ui.control.Spinner
        OLMethodDropDown              matlab.ui.control.DropDown
        StartthesimulationLabel       matlab.ui.control.Label
        ChoosehowthestatesshouldbedisplayedLabel  matlab.ui.control.Label
        ChoosewhichinputsstatesshouldbedisplayedLabel  matlab.ui.control.Label
        GoonestepforwardbackwardintimeLabel  matlab.ui.control.Label
        ClosedloopsimlationTab        matlab.ui.container.Tab
        EntertheinitialstateLabel_2   matlab.ui.control.Label
        ChoosehowmuchdisturbanceshouldbeaddedLabel  matlab.ui.control.Label
        ChoosehowthestatesshouldbedisplayedLabel_2  matlab.ui.control.Label
        ChoosewhichinputsstatesshouldbedisplayedLabel_2  matlab.ui.control.Label
        CLTable                       matlab.ui.control.Table
        CLmaxDist                     matlab.ui.control.NumericEditField
        ChoosenumberofsimulationstepsLabel  matlab.ui.control.Label
        CLNsimEditField               matlab.ui.control.NumericEditField
        StartthesimulationLabel_2     matlab.ui.control.Label
        CLSimulateButton              matlab.ui.control.Button
        CLMethodDropDown              matlab.ui.control.DropDown
        CLUDropDown                   matlab.ui.control.DropDown
        CLXDropDown                   matlab.ui.control.DropDown
        toLabel                       matlab.ui.control.Label
        CLminDist                     matlab.ui.control.NumericEditField
        CLNewSimulationButton         matlab.ui.control.Button
        PlantmodelmismatchTab         matlab.ui.container.Tab
        EntertheinitialstateLabel_3   matlab.ui.control.Label
        PMMTable                      matlab.ui.control.Table
        StartthesimulationLabel_4     matlab.ui.control.Label
        PMMSimulateButton             matlab.ui.control.Button
        EnterplantmodelmismatchLabel  matlab.ui.control.Label
        PMMInputButton                matlab.ui.control.Button
        PMMNewSimulationButton        matlab.ui.control.Button
        ChoosenumberofsimulationstepsLabel_2  matlab.ui.control.Label
        PMMNsimEditField              matlab.ui.control.NumericEditField
        ChoosethedisplaymethodLabel   matlab.ui.control.Label
        ChoosewhichinputsstatesshouldbedisplayedLabel_3  matlab.ui.control.Label
        PMMMethodDropDown             matlab.ui.control.DropDown
        PMMUDropDown                  matlab.ui.control.DropDown
        PMMXDropDown                  matlab.ui.control.DropDown
        ExplicitsolutionTab           matlab.ui.container.Tab
        StartthesimulationLabel_3     matlab.ui.control.Label
        SelectthedisplayedpartitionLabel  matlab.ui.control.Label
        InspectthecalculatedcoefficientsLabel  matlab.ui.control.Label
        EXRegionSimulateButton        matlab.ui.control.Button
        EXRegionSpinner               matlab.ui.control.Spinner
        EXKTable                      matlab.ui.control.Table
        EXbTable                      matlab.ui.control.Table
        KLabel                        matlab.ui.control.Label
        bLabel                        matlab.ui.control.Label
        uKxbLabel                     matlab.ui.control.Label
        EXNewSimulationButton         matlab.ui.control.Button
        PlotPanel                     matlab.ui.container.Panel
        ModelPredictiveControlLabel   matlab.ui.control.Label
        UIAxes1                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mpcObj                  % Mpc Object
        
        OLURes = [];            % OL simulation results for all inputs
        OLXRes = [];            % OL simulation results for all states
        OLUSeq = [];            % Sequence of inputs
        OLXSeq = [];            % Sequence of states  
        
        CLURes = [];            % CL simulation results for all inputs
        CLXRes = [];            % CL simulation results for all states        
        CLUDistRes = [];        % CL disturbance simulation results for all inputs
        CLXDistRes = [];        % CL disturbance simulation results for all states        
        CLDist = [];            % CL disturbances 
                
        MURes = [];             % CL simulation input results for the model
        MXRes = [];             % CL simulation state results for the model
        MCostRes = [];          % CL simulation cost results for the model
        PURes = [];             % CL simulation input results for the plant
        PXRes = [];             % CL simulation state results for the plant
        PCostRest = [];         % CL simulation cost results for the plant
           
        EXPartitions = [];      % Partitions of explicit solution    
        EXK = [];               % Coefficients K of all partitions
        EXb = [];               % Coefficients b of all partitions
    end
    
    properties (Access = public)
        xRef = [];              % Reference trajectories for the tracking problem
        xRefTableContent = [];  % Table content for reference GUI
        
        OLXInitial = [];        % Inital states for OL simulation
        OLX = [];               % States for OL Simulation
        
        CLXInitial = [];        % Inital states for CL simulation
        
        PMMXInitial = [];       % Initial states for the PMM simulation
        PlantModel = [];        % Plant model for CL simulation
        
        lastConfig = [];        % Last element configuration
        config = [];            % Current element configuration 
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
        
    methods (Access = public) 
        
        function resetSim(app)
            % Clear plots
            cla(app.UIAxes1,"reset");
            cla(app.UIAxes2,"reset");
            
            % Deactivate all elements on the simulation side
            all = findobj(app.UIFigure,'Tag','Element');
            for i=1:length(all)
                all(i).Enable = 'off';
            end
        end
        
        
        function elements(app)
            % Turn on/off elements according to config array
            configLoc = app.config;            
            
            % Model Panel
            app.SystemModelButton.Enable = configLoc(1);
            app.SystemModelLamp.Enable = configLoc(1);
            
            % Model Lamp
            if configLoc(2)
                app.SystemModelLamp.Color = 'g';
            else
                app.SystemModelLamp.Color = 'r';
            end
            
            % Constraints Panel
            app.ConstraintsButton.Enable = configLoc(3);
            app.ConstraintsLamp.Enable = configLoc(3);
            
            % Constraints Lamp
            if configLoc(4)
                app.ConstraintsLamp.Color = 'g';
            else
                app.ConstraintsLamp.Color = 'r';
            end
            
            % Penalties Panel
            app.PenaltiesButton.Enable = configLoc(5);
            app.PenaltiesLamp.Enable = configLoc(5);
            
            % Penalties Lamp
            if configLoc(6)
                app.PenaltiesLamp.Color = 'g';
            else
                app.PenaltiesLamp.Color = 'r';
            end
            
            % TSet Panel
            app.NotsetandtpenaltyButton.Enable = configLoc(7);
            app.ComputetsetandtpenaltyButton.Enable = configLoc(7);
            app.TSetPenaltyLamp.Enable = configLoc(7);
            
            % TSet Lamp
            if configLoc(8)
                app.TSetPenaltyLamp.Color = 'g';
            else
                app.TSetPenaltyLamp.Color = 'r';
            end
            
            % Horizon Panel
            app.HorizonEditField.Enable = configLoc(9);
            app.HorizonLamp.Enable = configLoc(9);
            
            % Horizon Lamp
            if configLoc(10)
                app.HorizonLamp.Color = 'g';
            else
                app.HorizonLamp.Color = 'r';
            end            
        end
        
        function updateMpc(app,mpc)
            % Update the systemmodel
            app.mpcObj = mpc;
                
            % Save model to workspace
            assignin("base","mpc",mpc)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Position GUI in the middle of the screen before it
            % becomes visible
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
            
            % Check if Multi-Parametric Toolbox is on the path
            try
                % Create empty LTI system
                model = LTISystem();
                
                % Create empty MPC Object
                app.mpcObj = MPCController(model,1);
                
                % Initialize element configuration
                app.config = [1,0,0,0,0,0,0,0,0,0];

            catch
                % Close figure and give hint if MPT is not on path
                fig = app.UIFigure;
                uialert(fig,'Please add the Multi-Parametric Toolbox to your path!','Warning',"CloseFcn",@(h,e) delete(fig),"Icon",'warning')                
            end
        end

        % Button pushed function: SystemModelButton
        function SystemModelButtonPushed(app, event)
            % Save current configuration
            app.lastConfig = app.config;
            
            % Turn off all elements
            app.config([1,3,5,7,9]) = 0;
            elements(app);
            
            % Call GUI for model input
            ModelGUI(app,app.mpcObj,app.lastConfig(10));
        end

        % Button pushed function: ConstraintsButton
        function ConstraintsButtonPushed(app, event)
            % Save current configuration
            app.lastConfig = app.config;
            
            % Turn off all elements
            app.config([1,3,5,7,9]) = 0;
            elements(app);
            
            % Call GUI for constraint input
            ConstraintGUI(app,app.mpcObj);
        end

        % Button pushed function: PenaltiesButton
        function PenaltiesButtonPushed(app, event)
            % Save current configuration
            app.lastConfig = app.config;
            
            % Turn off all elements
            app.config([1,3,5,7,9]) = 0;
            elements(app);
            
            % Call GUI for penalty input
            PenaltyGUI(app,app.mpcObj);
        end

        % Selection changed function: TSetPenaltyButtonGroup
        function TSetPenaltyButtonGroupSelectionChanged(app, event)
            % Change Lamp Color to red
            app.config(8) = 0;
            elements(app);
            
            % Get selected option 
            selectedOption = app.TSetPenaltyButtonGroup.SelectedObject.Text;  
            
            % Check which button is activated
            if strcmp(selectedOption,"No T-Set and T-Penalty")
                % Remove terminal set and penalty
                app.mpcObj.model.x.without('terminalSet');
                app.mpcObj.model.x.without('terminalPenalty');
                
                % Update entry text in the summary
                text = "No";
            else               
                try
                    % Compute terminal set
                    Tset = app.mpcObj.model.LQRSet;
                    
                    % Compute terminal weight
                    PN = app.mpcObj.model.LQRPenalty;
                    
                    % Add terminal set and terminal penalty
                    app.mpcObj.model.x.with('terminalSet');
                    app.mpcObj.model.x.terminalSet = Tset;
                    app.mpcObj.model.x.with('terminalPenalty');
                    app.mpcObj.model.x.terminalPenalty = PN;
                    
                    % Update entry text in the summary
                    text = "Yes";
                catch
                    % Catch computational error
                    uialert(app.UIFigure,"Can't determine terminal set and penalty!","Warning","Icon","warning")
                    
                    % Update entry text in the summary
                    text = "No";
                    
                    % Select first radiobutton
                    app.NotsetandtpenaltyButton.Value = 1;
                end
            end           
           
            % Update entry in the summary
            app.TerminalLabel.Text = text;
            
            % Change Lamp Color to green
            app.config(8) = 1;
            elements(app);
        end

        % Value changed function: HorizonEditField
        function HorizonEditFieldValueChanged(app, event)
            % Change lamp color to red
            app.config(10) = 0;
            elements(app);
                
            % Get prediction horizon
            horizon = app.HorizonEditField.Value;
            
            % Update horizon in the mpc object if valid
            if ~isinf(horizon)
                app.mpcObj.N = horizon;
                
                % Change lamp color to green
                app.config(10) = 1;
                elements(app);
                
                % Activate simulation elements
                app.ProblemSwitch.Enable = 'on'; 
                app.OLTable.Enable = 'on';
                app.CLTable.Enable = 'on';   
                app.PMMTable.Enable = 'on';
                app.EXRegionSimulateButton.Enable = "on";
                
                % Update entry in the summary
                app.HorizonLabel.Text = string(horizon);
            end  
        end

        % Menu selected function: SaveMPCMenu
        function SaveMPCMenuSelected(app, event)
            % Check if mpc has minimum content
            if ~isempty(app.mpcObj.nu) && ~isempty(app.mpcObj.nx) 
                % Generate timestamp in right format           
                timeStr = datestr(now,'dd mm yy,HH MM SS');
                timeStr = strrep(timeStr," ","_");
                timeStr = strrep(timeStr,",","-");
                
                % Generate mpc name
                mpcName = append("MPC-",timeStr,".mat");
                
                % Save mpc with generated name
                mpc = app.mpcObj;
                conf = app.config;
                
                % Let user specify the filename
                [file, path] = uiputfile(mpcName);
                
                % Use workaround to get GUI back to the front
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                
                if ~isequal(file,0) && ~isequal(path,0)
                    
                    % Save mpc to specified path
                    save(fullfile(path,file),'mpc','conf');         
                    
                    % Inform user of success
                    message = append("MPC saved as ",file);
                    uialert(app.UIFigure,message,"MPC saved!","Icon","success");
                end    
            else
               uialert(app.UIFigure,"Can't save empty MPC","MPC not saved!","Icon","warning"); 
            end
        end

        % Menu selected function: LoadMPCMenu
        function LoadMPCMenuSelected(app, event)
            % Open dialog for loading .mat-files
            [file,path] = uigetfile('*.mat');
            
            % Use workaround to get GUI back to the front
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            % Check if user selected a file
            if ~isequal(file,0)
                % Try to load the selected file
                try
                    mpcStruct = load(fullfile(path,file));
                    
                    % Save mpc object and configuration
                    app.mpcObj = mpcStruct.mpc;
                    app.config = mpcStruct.conf;
                    app.lastConfig = app.config; 
                    
                    % Update enabled elements
                    elements(app);
                    
                    % Update prediction horizon
                    app.HorizonEditField.Value = app.mpcObj.N;
                    
                    % Get number of inputs and states
                    Nu = app.mpcObj.model.nu;
                    Nx = app.mpcObj.model.nx;
        
                    % Create cell with state names and inital values
                    stateData = cell(1,Nx);                    
                    for i=1:Nx
                        stateData(1,i) = {char(append('x',string(i)))};
                        stateData(2,i) = {NaN};
                    end                                                                                       
                                    
                    % Update Columnnames
                    app.OLTable.ColumnName = stateData(1,:);
                    app.CLTable.ColumnName = stateData(1,:);
                    app.PMMTable.ColumnName = stateData(1,:);
                
                    % Update table content
                    app.OLTable.Data = stateData(2,:);
                    app.CLTable.Data = stateData(2,:);
                    app.PMMTable.Data = stateData(2,:);
                    
                    % Initialize arrays with NaN
                    app.OLXInitial = NaN(1,Nx);
                    app.OLX = NaN(1,Nx);
                    app.PMMXInitial = NaN(1,Nx);
                    app.CLXInitial = NaN(1,Nx);          
                    
                    % Fill dropdown menus with inputs
                    inputSelection(1) = "All Inputs";
                    for i=1:Nu
                        inputSelection(i+1) = append('Input ',string(i));
                    end   
                    app.OLUDropDown.Items = inputSelection;
                    app.CLUDropDown.Items = inputSelection;
                    app.PMMUDropDown.Items = inputSelection;
                    
                    % Connect inputs with numbers 0:Nu
                    app.OLUDropDown.ItemsData = 0:Nu;  
                    app.CLUDropDown.ItemsData = 0:Nu;
                    app.PMMUDropDown.ItemsData = 0:Nu;
                    
                    % Fill dropdown menus with states
                    stateSelection(1) = "All States";
                    for j=1:Nx
                        stateSelection(j+1) = append('State ',string(j));
                    end   
                    app.OLXDropDown.Items = stateSelection;
                    app.CLXDropDown.Items = stateSelection;
                    app.PMMXDropDown.Items = stateSelection;
                    
                    % Connect states with numbers 0:Nx
                    app.OLXDropDown.ItemsData = 0:Nx;
                    app.CLXDropDown.ItemsData = 0:Nx;
                    app.PMMXDropDown.ItemsData = 0:Nx;
                    
                    % Deactivate all simulation elements
                    resetSim(app)
                    
                    % Check if mpc was completely defined and enable simulation
                    % elements if it's the case
                    if app.config(10)
                        HorizonEditFieldValueChanged(app);
                    end
                    
                    % Update summary entries
                    % Input/State number
                    app.NuNxLabel.Text = append(string(Nu),"/",string(Nx));
                    
                    % Constraint number
                    if app.config(4)
                        inputConstraints = sum(~isinf(app.mpcObj.model.u.min)) + sum(~isinf(app.mpcObj.model.u.max));
                        stateConstraints = sum(~isinf(app.mpcObj.model.x.min)) + sum(~isinf(app.mpcObj.model.x.max));
                        app.ConstraintsLabel.Text = append(string(inputConstraints),"/",string(stateConstraints));
                    end
                    
                    % Penalty type
                    if app.config(6)
                        type = class(app.mpcObj.model.u.penalty);
                        switch(type)
                            case "QuadFunction"
                                text = "Quadratic";
                            case "OneNormFunction"
                                text = "One Norm";
                            case "InfNormFunction"
                                text = "Inf Norm";
                        end
                        app.PenaltiesLabel.Text = text;
                    end
                    
                    % Terminal Set/Penalty
                    try
                        app.mpcObj.model.x.terminalSet;
                        app.ComputetsetandtpenaltyButton.Value = 1;
                        text = "Yes";
                    catch
                        app.NotsetandtpenaltyButton.Value = 1;
                        text = "No";
                    end
                    app.TerminalLabel.Text = text;
                    
                    % Prediction horizon
                    app.HorizonLabel.Text = string(app.mpcObj.N);
                    
                    % Inform user of success
                    uialert(app.UIFigure,"MPC loaded successfully!","MPC loaded!","Icon","success");
                catch
                    uialert(app.UIFigure,"MPC could not be loaded!","Loading error","Icon","warning");
                end

            end

        end

        % Value changed function: ProblemSwitch
        function ProblemSwitchValueChanged(app, event)
            % Get selection
            selection = app.ProblemSwitch.Value;
            
            % Check whether a Tracking or Regulation problem was selcted
            if strcmp(selection,"Regulation")
                % Turn reference off
                app.mpcObj.model.x.without('reference');
                app.ReferenceTrajectoryButton.Enable = 'off';

            elseif strcmp(selection,"Tracking")
                % Turn reference on
                app.mpcObj.model.x.with('reference');                
                app.ReferenceTrajectoryButton.Enable = 'on';
                    
                % Update references in mpc object
                app.mpcObj.model.x.reference = 'free';             
            end
        end

        % Button pushed function: ReferenceTrajectoryButton
        function ReferenceTrajectoryButtonPushed(app, event)
            % Call GUI for reference trajectory input
            ReferenceGUI(app,app.mpcObj,app.xRefTableContent);
        end

        % Cell edit callback: OLTable
        function OLTableCellEdit(app, event)
            % Get row of entered data
            index = event.Indices;
            column = index(2);
            
            % Get entered data
            data = event.NewData;
            
            % Check if entered data is valid
            if isreal(data) && isscalar(data) && ~isempty(data) && ~isnan(data) && data >= app.mpcObj.model.x.min(column) && data <= app.mpcObj.model.x.max(column)
                % Update value in initial state array
                app.OLXInitial(column) = data;
                app.OLX = app.OLXInitial;
            else
                % Get last valid value if entered data is invalid
                app.OLTable.Data(1,column) = {app.OLXInitial(column)};
            end  
            
            % Check if all fields have been filled
            if all(~isnan(app.OLXInitial))
                app.OLSimulateButton.Enable = "on";       
            end
        end

        % Button pushed function: OLSimulateButton
        function OLSimulateButtonPushed(app, event)
            % Reset feasibility variable
            feasible = 0;
            
            % Check if all fields have been filled
            if all(~isnan(app.OLX))
                
                % Get selected option               
                if strcmp(app.ProblemSwitch.Value,"Regulation")
                    % Deactivate buttons until new simulation is pushed
                    app.ProblemSwitch.Enable = "off";
                    app.OLTable.Enable = 'off';
                    app.OLSimulateButton.Enable = 'off';
                    
                    % Set spinner limit to inf
                    app.OLStepSpinner.Limits = [0,inf];
                    
                    % Make OL simulation with states OLX
                    [~,feasible,openloop] = app.mpcObj.evaluate(app.OLX'); 
                    
                else    
                    if ~isempty(app.xRef)

                        % Check if MPC has a terminal set
                        selectedOption = app.TSetPenaltyButtonGroup.SelectedObject.Text;
                        if strcmp(selectedOption,"No T-Set and T-Penalty")
                            
                            % Deactivate buttons until new simulation is pushed
                            app.ProblemSwitch.Enable = "off";
                            app.ReferenceTrajectoryButton.Enable = "off";
                            app.OLTable.Enable = 'off';
                            app.OLSimulateButton.Enable = 'off';
                            
                            % Set spinner limit according to reference length
                            [~,columns] = size(app.xRef);
                            app.OLStepSpinner.Limits = [0,columns-1];
                            
                            % Get current spinner step
                            step = app.OLStepSpinner.Value;
                            
                            % Make OL simulation with states OLX and reference
                            % xRef
                            [~,feasible,openloop] = app.mpcObj.evaluate(app.OLX','x.reference',app.xRef(:,step+1));
                        else
                            uialert(app.UIFigure,"Tracking simulation is only available for a MPC without a terminal set. " + ...
                                "Please turn it off in panel 4 on the left side, if you still want to make an open loop simulation!","Simulation not available","Icon","warning");
                        end
        
                    else  
                        uialert(app.UIFigure,"Please define a reference trajectory!","Define reference!","Icon","warning");                      
                    end                  
                end  
            else
                uialert(app.UIFigure,"Please define an inital state!","Define initial state","Icon","warning");                
            end

            % Check if simulation was feasible
            if feasible
                % Save simulation results for the inputs
                app.OLURes = openloop.U';
                
                % Duplicate last entries for better display
                app.OLURes(end+1,:) = app.OLURes(end,:);
                
                % Save simulation results for the states
                app.OLXRes = openloop.X';
                
                % Get current timestep
                step = app.OLStepSpinner.Value;
                
                % Save sequence of inputs
                app.OLUSeq(:,step+1) = app.OLURes(1,:)';
                
                % Save sequence of states
                app.OLXSeq(:,step+1) = app.OLXRes(2,:)';                
                
                % Enable elements for displaychanges
                app.OLMethodDropDown.Enable = "on";
                app.OLUDropDown.Enable = "on";
                app.OLXDropDown.Enable = "on";
                app.OLStepSpinner.Enable = "on";
                app.OLNewSimulationButton.Enable = "on";
                              
                % Call functions for updating the displayed plots
                OLUDropDownValueChanged(app);
                OLMethodDropDownValueChanged(app);
                
            else
                uialert(app.UIFigure,"Simulation not feasible for this configuration!","Simulation infeasible!","Icon","warning");
                OLNewSimulationButtonPushed(app)
            end
        end

        % Value changed function: OLMethodDropDown
        function OLMethodDropDownValueChanged(app, event)
            % Get selected displaymethod
            method = app.OLMethodDropDown.Value;
            
            if strcmp(method,"Time")
                 % Turn on dropdown for state selection
                app.OLXDropDown.Enable = "on";                
                
                % Call OLX DropDownChange to display results over time
                OLXDropDownValueChanged(app);
            else               
                % Check if systemmodel has at least two states and is a
                % Regulation problem
                if app.mpcObj.nx == 2 && strcmp(app.ProblemSwitch.Value,"Regulation")
                    
                    % Turn off dropdown for state selection
                    app.OLXDropDown.Enable = "off";                
                    
                    % Clear axes
                    cla(app.UIAxes2,'reset')
                    
                    % Create modified matrix shifted by 1 and with inital state
                    [rows,columns] = size(app.OLXSeq);
                    OLXSeqShift = zeros(rows,columns+1);
                    OLXSeqShift(:,2:end) = app.OLXSeq;
                    OLXSeqShift(:,1) = app.OLXInitial(1,:);
                        
                    % Get current timestep
                    step = app.OLStepSpinner.Value;
                    
                    % Get copy of mpc object
                    mpc = app.mpcObj;
                    
                    % Generate explicit controller
                    expMpc = mpc.toExplicit();
                    
                    % Get explicit partitions
                    expMpc.partition.plot()
                    
                    % Save figure and axes
                    figExp = gcf;
                    axExp = gca;
                    
                    % Copy axes content into GUI axes
                    copyobj(get(axExp,'Children'),app.UIAxes2);
                    
                    % Take over x- and y-ticks
                    app.UIAxes2.XTick = axExp.XTick;
                    app.UIAxes2.YTick = axExp.YTick;
                    
                    % Take over aspect ratio
                    app.UIAxes2.PlotBoxAspectRatio = axExp.PlotBoxAspectRatio;
                    
                    % Close figure after copying
                    delete(figExp);
                    
                    % Wait for other plots
                    hold(app.UIAxes2,'on')
                    
                    % Plot open-loop-history if possible
                    if step > 0
                        plot(app.UIAxes2,OLXSeqShift(1,1:step+1),OLXSeqShift(2,1:step+1),'LineStyle',"-.",'LineWidth',2,'Color','k','Marker','x','MarkerSize',8)
                    end
                    
                    % Plot open-loop-result
                    plot(app.UIAxes2,app.OLXRes(:,1),app.OLXRes(:,2),"LineWidth",2,'Color','k','Marker','x','MarkerSize',8)
                                      
                    % Find all polyhedron handles and set handlevisibility off
                    poly = findobj(app.UIAxes2,'LineWidth',1);
                    for i=1:length(poly)
                        poly(i,1).HandleVisibility = 'off';
                    end
                    
                    % Show legend
                    if step > 0                        
                        legend(app.UIAxes2,"State Sequence","Open Loop Simulation");
                    else
                        legend(app.UIAxes2,"Open Loop Simulation");
                    end  
                    
                    % Change title
                    title(app.UIAxes2,"Open Loop Simulation - States");
                    
                    % Set x- and y-label
                    xlabel(app.UIAxes2,"x1")
                    ylabel(app.UIAxes2,"x2")
                else
                   % Reset the DropDown Menu
                   app.OLMethodDropDown.Value = "Time";
                   uialert(app.UIFigure,"State-space display is only available for a regulation problem with two states!","State-space representation not available!","Icon","warning");
                end               
            end    
        end

        % Value changed function: OLUDropDown
        function OLUDropDownValueChanged(app, event)
            % Get selected input
            selection = app.OLUDropDown.Value;
            
            % Get sample time
            Ts = app.mpcObj.model.Ts;
            
            % Clear axes
            cla(app.UIAxes1,'reset')
            
            % Get number of inputs and prediction horizon
            [horizon,inputs] = size(app.OLURes);
            
            % Reduce horizon by one because of the added last value
            horizon = horizon - 1;
            
            % Get current timestep
            step = app.OLStepSpinner.Value;            
                
            % Plotte je nach Eingangsanzahl 
            if inputs == 1 || selection > 0
                if inputs == 1
                    selection = 1;
                end

                % Plot input as stairfunction                   
                stairs(app.UIAxes1,0:Ts:horizon*Ts,app.OLURes(:,selection),'LineWidth',2,'Color','b');  
                hold(app.UIAxes1,'on')
                
                % Plot constraints                
                plot(app.UIAxes1,-2*Ts:Ts:(horizon+1)*Ts,ones(1,horizon+4)*app.mpcObj.model.u.min(selection),'LineWidth',1,'Linestyle','-.','Color','r','HandleVisibility','off')
                plot(app.UIAxes1,-2*Ts:Ts:(horizon+1)*Ts,ones(1,horizon+4)*app.mpcObj.model.u.max(selection),'LineWidth',1,'Linestyle','-.','Color','r','HandleVisibility','off')
                    
                % Create array with past 3 input results
                history = zeros(3,1);
                    
                % Plot last inputs depending on timestep    
                if step == 1
                    % Create array with last and current input result
                    history(1,1) = app.OLUSeq(selection,1); 
                    history(2,1) = app.OLURes(1,selection);
                        
                    % Plot input sequence as stairfunction
                    stairs(app.UIAxes1,-Ts:Ts:0,history(1:2,1),'LineWidth',2,'Color','c')
                        
                    % Set legend names
                    legNames = [append("Input ",string(selection)),"Input Sequence","Present"];
                elseif step > 1
                    % Create array with last 2 and current input results                           
                    history(1:2,1) = app.OLUSeq(selection,step-1:step);
                    history(3,1) = app.OLURes(1,selection);
                        
                    % Plot input sequence as stairfunction
                    stairs(app.UIAxes1,-2*Ts:Ts:0,history,'LineWidth',2,'Color','c')  
                        
                    % Set legend names
                    legNames = [append("Input ",string(selection)),"Input Sequence","Present"];
                else
                    % Set legend names
                    legNames = [append("Input ",string(selection)),"Present"];
                end                   
                    
                % Calculate limit for y-axis
                minimum = app.mpcObj.model.u.min(selection)-abs(app.mpcObj.model.u.min(selection))*0.25;
                maximum = app.mpcObj.model.u.max(selection)+abs(app.mpcObj.model.u.min(selection))*0.25;
                
                % Set min/max to -1/1 if the calculated values are both 0
                if minimum == 0 && maximum == 0
                    minimum = -1;
                    maximum = 1;
                end
            
            elseif selection == 0
                
                % Plot all inputs as stairfunctions
                stairs(app.UIAxes1,0:Ts:horizon*Ts,app.OLURes,'LineWidth',2);  
                hold(app.UIAxes1,'on')
                    
                % Create array with past 3 results for each input
                history = zeros(3,inputs);
                
                % Plot last input results depending on timestep 
                if step == 1
                    % Create array out of last and current input result
                    for k=1:inputs
                        history(1,k) = app.OLUSeq(k,1); 
                        history(2,k) = app.OLURes(1,k);
                    end
                        
                    % Plot input sequences as stairfunctions
                    stairs(app.UIAxes1,-Ts:Ts:0,history(1:2,:),'LineWidth',2,'HandleVisibility','off')

                elseif step > 1
                    % Create array out of last 2 and current input result     
                    for k=1:inputs
                        history(1:2,k) = app.OLUSeq(k,step-1:step);
                        history(3,k) = app.OLURes(1,k);
                    end
                        
                    % Plot input sequences as stairfunction
                    stairs(app.UIAxes1,-2*Ts:Ts:0,history,'LineWidth',2,'HandleVisibility','off')  
                    
                end
                 
                % Set legend names
                for i=1:inputs
                    legNames(i) = append("Input ",string(i));
                end
                legNames(inputs+1) = "Present";
                    
                    
                % Set limit for y-axis                       
                minY = min([min(min(app.OLURes)),min(min(history))]);
                maxY = max([max(max(app.OLURes)),max(max(history))]);
                
                minimum = minY-(abs(maxY)+abs(minY))*0.15;
                maximum = maxY+(abs(maxY)+abs(minY))*0.25;  
                  
                % Set min/max to -1/1 if the calculated values are both 0
                if minimum == 0 && maximum == 0
                    minimum = -1;
                    maximum = 1;
                end
            end
            
            % Plot vertical line, which indicates the present
            xl = xline(app.UIAxes1,0,'-',{'  \leftarrow Past Future \rightarrow'},'LineWidth',1.5);
            xl.LabelHorizontalAlignment = 'center';
            xl.LabelVerticalAlignment = 'top';
            xl.LabelOrientation = 'horizontal';    
                
            % Set limit for x-axis
            xlim(app.UIAxes1,[-2*Ts (horizon+1)*Ts])
                
            % Set limit for y-axis
            ylim(app.UIAxes1,[minimum maximum])        
            
            % Set x-ticks so that the distance between them is Ts
            xticks(app.UIAxes1,-2*Ts:Ts:(horizon+1)*Ts)
    
            % Change title of the diagram
            title(app.UIAxes1,"Open Loop Simulation - Inputs")
            
            % Set xlabel
            xlabel(app.UIAxes1,"k")
            
            % Show legend
            legend(app.UIAxes1,legNames)
    
            % Stop plotting
            hold(app.UIAxes1,'off') 
        end

        % Value changed function: OLXDropDown
        function OLXDropDownValueChanged(app, event)
            % Get selected input
            selection = app.OLXDropDown.Value;
            
            % Get sample time
            Ts = app.mpcObj.model.Ts;
            
            % Clear axes
            cla(app.UIAxes2,'reset')
            
            % Get number of states and prediction horizon
            [horizon,states] = size(app.OLXRes);
            
            % Create modified matrix shifted by 1 and with inital state
            [rows,columns] = size(app.OLXSeq);
            OLXSeqShift = zeros(rows,columns+1);
            OLXSeqShift(:,2:end) = app.OLXSeq;
            OLXSeqShift(:,1) = app.OLXInitial(1,:);
                
            % Get current timestep
            step = app.OLStepSpinner.Value;

            % Plot depending on number of states
            if states == 1 || selection > 0
                if states == 1
                    selection = 1;
                end
                
                % Plot state                  
                plot(app.UIAxes2,0:Ts:(horizon-1)*Ts,app.OLXRes(:,selection),'LineWidth',2,'Color','b');  
                hold(app.UIAxes2,'on')
                    
                % Plot reference if tracking is turned on
                if strcmp(app.ProblemSwitch.Value,"Tracking")
                    plot(app.UIAxes2,0:Ts:(horizon-1)*Ts,(ones(1,horizon).*app.xRef(selection,step+1))','LineWidth',1,'Linestyle','--')
                end
                
                % Plot constraints                   
                plot(app.UIAxes2,-2*Ts:Ts:horizon*Ts,ones(1,horizon+3)*app.mpcObj.model.x.min(selection),'LineWidth',1,'Linestyle','-.','Color','r','HandleVisibility','off')
                plot(app.UIAxes2,-2*Ts:Ts:horizon*Ts,ones(1,horizon+3)*app.mpcObj.model.x.max(selection),'LineWidth',1,'Linestyle','-.','Color','r','HandleVisibility','off')
                    
                % Create array with last 3 state results
                history = zeros(3,1);
                    
                % Plotte letzte Zustände je nach Schrittwert    
                if step == 1
                    % Create array out of last and current state result
                    history(1,1) = OLXSeqShift(selection,1); 
                    history(2,1) = app.OLXRes(1,selection);
                        
                    % Plot state sequence
                    plot(app.UIAxes2,-Ts:Ts:0,history(1:2,1),'LineWidth',2,'Color','c')
                        
                    % Set legend names
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        legNames = [append("State ",string(selection)),"State Sequence","Present"];
                    else
                        legNames = [append("State ",string(selection)),append("Reference ",string(selection)),"State Sequence","Present"];
                    end
                    
                elseif step > 1
                    % Create array with last 2 and current state result                           
                    history(1:2,1) = OLXSeqShift(selection,step-1:step);
                    history(3,1) = app.OLXRes(1,selection);
                        
                    % Plot state sequence
                    plot(app.UIAxes2,-2*Ts:Ts:0,history,'LineWidth',2,'Color','c')  
                        
                    % Set legend names
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        legNames = [append("State ",string(selection)),"State Sequence","Present"];
                    else
                        legNames = [append("State ",string(selection)),append("Reference",string(selection)),"State Sequence","Present"];
                    end
                else
                    % Set legend names
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        legNames = [append("State ",string(selection)),"Present"];
                    else
                        legNames = [append("State ",string(selection)),append("Reference ",string(selection)),"Present"];
                    end
                end                        
                    
                % Calculate limit for y-axis
                minimum = app.mpcObj.model.x.min(selection)-abs(app.mpcObj.model.x.min(selection))*0.25;
                maximum = app.mpcObj.model.x.max(selection)+abs(app.mpcObj.model.x.min(selection))*0.25;  
                
                % Set min/max to -1/1 if the calculated values are both 0
                if minimum == 0 && maximum == 0
                    minimum = -1;
                    maximum = 1;
                end
                    
            elseif selection == 0
                
                % Plot state results
                plot(app.UIAxes2,0:Ts:(horizon-1)*Ts,app.OLXRes,'LineWidth',2);  
                hold(app.UIAxes2,'on')
                    
                % Plot references if tracking is turned on
                if strcmp(app.ProblemSwitch.Value,"Tracking")
                    plot(app.UIAxes2,0:Ts:(horizon-1)*Ts,(ones(states,horizon).*app.xRef(:,step+1))','LineWidth',1,'Linestyle','--')
                end
                    
                % Create array with last 3 state results
                history = zeros(3,states);
                
                % Plot last state results depending on timestep
                if step == 1
                    % Create matrix out of last and current state results
                    for k=1:states
                        history(1,k) = OLXSeqShift(k,1); 
                        history(2,k) = app.OLXRes(1,k);
                    end
                        
                    % Plot state sequences
                    plot(app.UIAxes2,-Ts:Ts:0,history(1:2,:),'LineWidth',2,'HandleVisibility','off')                               
                    
                elseif step > 1
                    % Create matrix out of last 2 and current state results    
                        for k=1:states
                            history(1:2,k) = OLXSeqShift(k,step-1:step);
                            history(3,k) = app.OLXRes(1,k);
                        end
                        
                    % Plot state sequences 
                    plot(app.UIAxes2,-2*Ts:Ts:0,history,'LineWidth',2,'HandleVisibility','off')  

                end                       
                    
                % Set legend names
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        for i=1:states
                            legNames(i) = append("State ",string(i));                            
                        end
                        legNames(states+1) = "Present";
                    else
                        for i=1:states
                            legNames(i) = append("State ",string(i));
                            legNames(states+i) = append("Reference ",string(i));
                        end
                        legNames(states*2+1) = "Present";                         
                    end
                    
                % Calculate limit for y-axis
                minY = min([min(min(app.OLXRes)),min(min(history))]);
                maxY = max([max(max(app.OLXRes)),max(max(history))]);
                
                minimum = minY-(abs(maxY)+abs(minY))*0.15;
                maximum = maxY+(abs(maxY)+abs(minY))*0.25;  
                
                % Set min/max to -1/1 if the calculated values are both 0
                if minimum == 0 && maximum == 0
                    minimum = -1;
                    maximum = 1;
                end
                                            
            end
                           
            % Plot vertical line indicating the present
            xl = xline(app.UIAxes2,0,'-',{'  \leftarrow Past Future \rightarrow'},'LineWidth',1.5);
            xl.LabelHorizontalAlignment = 'center';
            xl.LabelVerticalAlignment = 'top';
            xl.LabelOrientation = 'horizontal';                 
                
            % Set limit for x-axis
            xlim(app.UIAxes2,[-2*Ts horizon*Ts])
                
            % Set limit for y-axis
            ylim(app.UIAxes2,[minimum maximum])
                            
            % Set x-ticks so that the distance between them is Ts
            xticks(app.UIAxes2,-2*Ts:Ts:horizon*Ts)

            % Change title of the diagram
            title(app.UIAxes2,"Open Loop Simulation - States")
            
            % Set xlabel
            xlabel(app.UIAxes2,"k")
            
            % Show legend
            legend(app.UIAxes2,legNames)

            % Stop plotting
            hold(app.UIAxes2,'off')
        end

        % Value changed function: OLStepSpinner
        function OLStepSpinnerValueChanged(app, event)
            % Turn off spinner for duration of the OL simulation
            app.OLStepSpinner.Enable = 'off';
            
            % Get current timestep
            step = app.OLStepSpinner.Value;
            
            % If step if greater 0, get columns of saved states
            if step > 0
                app.OLX = app.OLXSeq(:,step)';
            else
                % Simulate with inital state
                app.OLX = app.OLXInitial;
            end
            
            % Make OL simulation
            OLSimulateButtonPushed(app);
            
            % Turn spinner back on
            app.OLStepSpinner.Enable = 'on'; 
        end

        % Button pushed function: OLNewSimulationButton
        function OLNewSimulationButtonPushed(app, event)
            % Activate selection and input elements
            app.ProblemSwitch.Enable = "on";
            app.OLTable.Enable = "on";
            if strcmp(app.ProblemSwitch.Value,"Tracking")
                app.ReferenceTrajectoryButton.Enable = "on";
            end
            
            % Reset results
            app.OLURes = [];
            app.OLUSeq = [];
            app.OLXRes = [];
            app.OLXSeq = [];
            app.OLXInitial = [];        
            app.OLX = [];            
            
            % Reset initial states
            Nx = app.mpcObj.nx;
            OLData = cell(1,Nx);
            OLData(1,:) = {nan};
            app.OLTable.Data = OLData;
            app.OLXInitial = NaN(1,Nx);
            app.OLX = NaN(1,Nx);

            % Reset timestepspinner
            app.OLStepSpinner.Value = 0;
                
            % Reset dropdown menus
            app.OLUDropDown.Value = 0;
            app.OLXDropDown.Value = 0;
            app.OLMethodDropDown.Value = "Time";
                
            % Deactivate elements for step- and displaychanges
            app.OLUDropDown.Enable = 'off';
            app.OLXDropDown.Enable = 'off';
            app.OLStepSpinner.Enable = 'off'; 
            app.OLMethodDropDown.Enable = 'off';
            app.OLNewSimulationButton.Enable = 'off';
        end

        % Cell edit callback: CLTable
        function CLTableCellEdit(app, event)
            % Get column of entered data
            indices = event.Indices;
            column = indices(2);
            
            % Get entered data
            data = event.NewData;
            
            % Check whether input is valid
            if isreal(data) && isscalar(data) && ~isempty(data) && ~isnan(data) && data >= app.mpcObj.model.x.min(column) && data <= app.mpcObj.model.x.max(column)
                % Update field in inital state array
                app.CLXInitial(column) = data;
            else
                % Show last valid value if input is invalid
                app.CLTable.Data(1,column) = {app.CLXInitial(column)};
            end

            % Check if all fields have been filled
            if all(~isnan(app.CLXInitial))
                % Enable simulation elements
                app.CLminDist.Enable = "on";
                app.CLmaxDist.Enable = "on";
                if strcmp(app.ProblemSwitch.Value,"Regulation")
                    app.CLNsimEditField.Enable = "on";
                end
                app.CLSimulateButton.Enable = "on";
            end            
        end

        % Value changed function: CLminDist
        function CLminDistValueChanged(app, event)
            % Get entered data
            data = app.CLminDist.Value;
            
            if data >= app.CLmaxDist.Value
                app.CLmaxDist.Value = app.CLminDist.Value;
            end   
        end

        % Value changed function: CLmaxDist
        function CLmaxDistValueChanged(app, event)
            % Get entered data
            data = app.CLmaxDist.Value;
            
            if data <= app.CLminDist.Value
                app.CLminDist.Value = app.CLmaxDist.Value;
            end  
        end

        % Button pushed function: CLSimulateButton
        function CLSimulateButtonPushed(app, event)
            % Check if all fields have been filled
            if all(~isnan(app.CLXInitial))
                if strcmp(app.ProblemSwitch.Value,"Regulation")
                    % Deactivate buttons until new simulation is pushed
                    app.ProblemSwitch.Enable = "off";
                    app.CLSimulateButton.Enable = "off";
                    app.CLTable.Enable = "off";
                    app.CLminDist.Enable = "off";
                    app.CLmaxDist.Enable = "off";
                    app.CLNsimEditField.Enable = "off";
                    
                    % Get prediction horizon
                    Nsim = app.CLNsimEditField.Value;
                    
                    % Get inital states
                    x0 = app.CLXInitial';
                    
                    % Get lower and upper bound of disturbance
                    distMin = app.CLminDist.Value;
                    distMax = app.CLmaxDist.Value;
                    
                    % Use systemmodel for closed loop simulation
                    model = LTISystem('A',app.mpcObj.model.A,'B',app.mpcObj.model.B);
                    loop = ClosedLoop(app.mpcObj,model);
                    
                    % Make closed loop simulation without disturbance
                    results = loop.simulate(x0,Nsim);

                    % Initialize result matrices
                    XDist =[];
                    XDist = [x0 XDist];
                    UDist = [];
                    x0Dist = x0;
                    Disturbance = zeros(length(x0),1);
                    
                    % Initialize model with initial states
                    app.mpcObj.model.initialize(x0);
           
                    % Try to run simulation
                    try
                        % Make stepwise closed loop simulation
                        for i=1:Nsim  
                            % Get input for disturbed state                      
                            [u0Dist,~,openloop] = app.mpcObj.evaluate(x0Dist);
                            
                            % Get state for calculated input
                            x0 = app.mpcObj.model.update(u0Dist);
                            
                            % Apply disturbance by adding randon vector in
                            % entered interval
                            dist = (distMax-distMin).*rand(length(x0),1) + distMin;
                            x0Dist = dist + x0;
                            
                            % Save states, inputs and disturbances
                            XDist = [XDist x0Dist];
                            UDist = [UDist u0Dist]; 
                            Disturbance = [Disturbance dist];
                        end
                    catch
                        uialert(app.UIFigure,"Simulation not feasible for this configuration!","Simulation infeasible!","Icon","warning");
                    end 
                    
                    % Save results of undisturbed and disturbed CL simulation
                    app.CLURes = results.U';
                    app.CLXRes = results.X';
                    app.CLXDistRes = XDist';
                    app.CLUDistRes = UDist';
                    app.CLDist = Disturbance';
                
                                
                    % Plot only if the simulation was successfull
                    Nsim = length(app.CLURes);
                    if Nsim == app.CLNsimEditField.Value
                        % Plot results depending on selection
                        CLUDropDownValueChanged(app);
                        CLXDropDownValueChanged(app);
                        
                        % Enable elements for displaychanges
                        app.CLMethodDropDown.Enable = "on";
                        app.CLUDropDown.Enable = "on";
                        app.CLXDropDown.Enable = "on";
                        app.CLNewSimulationButton.Enable = "on";
                    else
                        uialert(app.UIFigure,"Simulation not feasible for this configuration!","Simulation infeasible!","Icon","warning");
                        CLNewSimulationButtonPushed(app)
                    end
                else
                    if ~isempty(app.xRef)
                        
                        % Check if MPC has a terminal set
                        selectedOption = app.TSetPenaltyButtonGroup.SelectedObject.Text;
                        if strcmp(selectedOption,"No T-Set and T-Penalty")
 
                            % Deactivate buttons until new simulation is pushed
                            app.ProblemSwitch.Enable = "off";
                            app.ReferenceTrajectoryButton.Enable = "off";
                            app.CLSimulateButton.Enable = "off";
                            app.CLTable.Enable = "off";
                            app.CLminDist.Enable = "off";
                            app.CLmaxDist.Enable = "off";
                            app.CLNsimEditField.Enable = "off";
                            
                            % Get prediction horizon
                            Nsim = length(app.xRef);
                            app.CLNsimEditField.Value = Nsim;
                            
                            % Get inital states
                            x0 = app.CLXInitial';
                            
                            % Get lower and upper bound of disturbance
                            distMin = app.CLminDist.Value;
                            distMax = app.CLmaxDist.Value;
                            
                            % Use systemmodel for closed loop simulation
                            model = LTISystem('A',app.mpcObj.model.A,'B',app.mpcObj.model.B);
                            loop = ClosedLoop(app.mpcObj,model);
                            
                            % Make closed loop simulation without disturbance but
                            % with reference
                            results = loop.simulate(x0,Nsim,'x.reference',app.xRef);
                            
                            % Initialize result matrices
                            XDist =[];
                            XDist = [x0 XDist];
                            UDist = [];
                            x0Dist = x0;
                            Disturbance = zeros(length(x0),1);
                            
                            % Initialize model with initial states
                            app.mpcObj.model.initialize(x0);                       
                                                 
                            % Try to run simulation
                            try
                                % Make stepwise closed loop simulation
                                for i=1:Nsim  
                                    % Get input for disturbed state
                                    u0Dist = app.mpcObj.evaluate(x0Dist,'x.reference',app.xRef(:,i));
                                    
                                    % Get state for calculated input
                                    x0 = app.mpcObj.model.update(u0Dist);
                                    
                                    % Apply disturbance by adding randon vector in
                                    % entered interval
                                    dist = (distMax-distMin).*rand(length(x0),1) + distMin;
                                    x0Dist = dist + x0;
                                    
                                    % Save states, inputs and disturbances
                                    XDist = [XDist x0Dist];
                                    UDist = [UDist u0Dist]; 
                                    Disturbance = [Disturbance dist];
                                end
                            catch
                                uialert(app.UIFigure,"Simulation not feasible for this configuration!","Simulation infeasible!","Icon","warning");
                            end 
                            
                            % Save results of undisturbed and disturbed CL simulation
                            app.CLURes = results.U';
                            app.CLXRes = results.X';
                            app.CLXDistRes = XDist';
                            app.CLUDistRes = UDist';
                            app.CLDist = Disturbance';
              
                            % Plot only if the simulation was successfull
                            Nsim = length(app.CLURes);
                            if Nsim == app.CLNsimEditField.Value
                                % Plot results depending on selection
                                CLUDropDownValueChanged(app);
                                CLXDropDownValueChanged(app);
                                
                                % Enable elements for displaychanges
                                app.CLMethodDropDown.Enable = "on";
                                app.CLUDropDown.Enable = "on";
                                app.CLXDropDown.Enable = "on";
                                app.CLNewSimulationButton.Enable = "on";
                            else
                                uialert(app.UIFigure,"Simulation not feasible for this configuration!","Simulation infeasible!","Icon","warning");
                                CLNewSimulationButtonPushed(app)
                            end
                        else
                            uialert(app.UIFigure,"Tracking simulation is only available for a MPC without a terminal set. " + ...
                                "Please turn it off in panel 4 on the left side, if you still want to make a closed loop simulation!","Simulation not available","Icon","warning");
                        end
                    else
                        uialert(app.UIFigure,"Please define a reference trajectory!","Define reference!","Icon","warning");
                    end
                end
            else
                uialert(app.UIFigure,"Please define an inital state!","Define initial state","Icon","warning");                
            end
        end

        % Value changed function: CLMethodDropDown
        function CLMethodDropDownValueChanged(app, event)
            % Get selected displaymethod
            method = app.CLMethodDropDown.Value;
            
            if strcmp(method,"Time")
                % Turn on dropdown for state selection
                app.CLXDropDown.Enable = "on";                
                
                % Call CLX DropDownChange to display results over time
                CLXDropDownValueChanged(app);
            else
                
                % Check if systemmodel has at least two states
                if app.mpcObj.nx == 2 && strcmp(app.ProblemSwitch.Value,"Regulation")
                  
                    % Clear axes
                    cla(app.UIAxes2,'reset')
                    
                    % Turn off dropdown for state selection
                    app.CLXDropDown.Enable = "off"; 
                    
                    % Get copy of mpc object
                    mpc = app.mpcObj;                    
                    
                    % Generate explicit controller
                    expMpc = mpc.toExplicit();
                    
                    % Get explicit partitions
                    expMpc.partition.plot()
                    
                    % Save figure and axes
                    figExp = gcf;
                    axExp = gca;
                    
                    % Copy axes content into GUI axes
                    copyobj(get(axExp,'Children'),app.UIAxes2);
                    
                    % Take over x- and y-ticks
                    app.UIAxes2.XTick = axExp.XTick;
                    app.UIAxes2.YTick = axExp.YTick;
                    
                    % Take over aspect ratio
                    app.UIAxes2.PlotBoxAspectRatio = axExp.PlotBoxAspectRatio;
                    
                    % Close figure after copying
                    delete(figExp);
                    
                    % Wait for other plots
                    hold(app.UIAxes2,'on')
                    
                    % Plot closed loop simulation in state-space
                    plot(app.UIAxes2,app.CLXRes(:,1),app.CLXRes(:,2),"LineWidth",2,'Color','k','Marker','x','MarkerSize',8);                  
                    
                    % Find all polyhedron handles and set handlevisibility off
                    poly = findobj(app.UIAxes2,'LineWidth',1);
                    for i=1:length(poly)
                        poly(i,1).HandleVisibility = 'off';
                    end
                    
                    % Show legend
                    legend(app.UIAxes2,"Closed Loop Simulation");
                    
                    % Set x- and y-label
                    xlabel(app.UIAxes2,"x1")
                    ylabel(app.UIAxes2,"x2")
                    
                    % Change title
                    title(app.UIAxes2,"Closed Loop Simulation - States");              
                else
                    % Reset the DropDown Menu
                   app.CLMethodDropDown.Value = "Time";
                   uialert(app.UIFigure,"State-space display is only available for a regulation problem with two states!","State-space representation not available!","Icon","warning");
                end                
            end
        end

        % Value changed function: CLUDropDown
        function CLUDropDownValueChanged(app, event)
            % Get selected input
            selection = app.CLUDropDown.Value;
            
            % Get sample time
            Ts = app.mpcObj.model.Ts;
            
            % Clear all plots
            cla(app.UIAxes1,'reset');
            
            % Get number of inputs and timesteps
            [Nsim,inputs] = size(app.CLURes);

            % Plot depending on number of inputs
            if inputs == 1 || selection > 0
                % Set selection to 1 if there is only 1 input
                if inputs == 1
                    selection = 1;
                end
                
                % Plot input as stairfunction
                stairs(app.UIAxes1,0:Ts:(Nsim-1)*Ts,app.CLURes(:,selection),'LineWidth',2,'Color','b');
                hold(app.UIAxes1,'on');
                
                % Plot input with disturbance (if applicable) as stairfunction
                if ~all(all(app.CLDist == 0))
                    stairs(app.UIAxes1,0:Ts:(Nsim-1)*Ts,app.CLUDistRes(:,selection),'LineWidth',2,'Color','r');
                    
                    % Set legend names
                    legNames = [append("Input ",string(selection)),append("Disturbed Input ",string(selection)),"Present"]; 
                else
                    % Set legend names
                    legNames = [append("Input ",string(selection)),"Present"];
                end
                
                % Plot constraints
                plot(app.UIAxes1,-2*Ts:Ts:(Nsim+1)*Ts,ones(1,Nsim+4)*app.mpcObj.model.u.min(selection),'LineWidth',1,"LineStyle","-.",'Color','r','HandleVisibility','off');
                plot(app.UIAxes1,-2*Ts:Ts:(Nsim+1)*Ts,ones(1,Nsim+4)*app.mpcObj.model.u.max(selection),'LineWidth',1,"LineStyle","-.",'Color','r','HandleVisibility','off');
                     
                % Calculate limit for y-axis
                minimum = app.mpcObj.model.u.min(selection)-abs(app.mpcObj.model.u.min(selection))*0.25;
                maximum = app.mpcObj.model.u.max(selection)+abs(app.mpcObj.model.u.max(selection))*0.25;
                
            elseif selection == 0
                % Plot all inputs as stairfunctions
                stairs(app.UIAxes1,0:Ts:(Nsim-1)*Ts,app.CLURes,'LineWidth',2);
                hold(app.UIAxes1,'on')
                
                % Plot all inputs with distubances (if applicable) as stairfunctions
                if ~all(all(app.CLDist == 0))
                    stairs(app.UIAxes1,0:Ts:(Nsim-1),app.CLUDistRes,'LineWidth',2)
                    
                    % Set legend names
                    for i=1:inputs
                        legNames(i) = append("Input ",string(i));
                        legNames(inputs+i) = append("Disturbed Input ",string(i));
                    end                
                    legNames(inputs*2+1) = "Present";
                else
                    % Set legend names
                    for i=1:inputs
                        legNames(i) = append("Input ",string(i));
                    end                
                    legNames(inputs+1) = "Present";
                end
                        
                % Calculate limit for y-axis
                minY = min(min(app.CLURes));
                maxY = max(max(app.CLURes));
                
                minimum = minY-(abs(maxY)+abs(minY))*0.15;
                maximum = maxY+(abs(maxY)+abs(minY))*0.25; 
               
            end
                
            % Plot vertical line to indicate present
            xl = xline(app.UIAxes1,0,'-',{'  \leftarrow Past Future \rightarrow'},'LineWidth',1.5);
            xl.LabelHorizontalAlignment = 'center';
            xl.LabelVerticalAlignment = 'top';
            xl.LabelOrientation = 'horizontal';    
                
            % Set limit for x-axis
            xlim(app.UIAxes1,[-2*Ts (Nsim+1)*Ts])
            
            if minimum == 0 && maximum == 0              
                minimum = -1;
                maximum = 1;
            end
            
            % Set limit for y-axis
            ylim(app.UIAxes1,[minimum maximum])                          
                
            % Set x-ticks so that Ts is distance between them
            xticks(app.UIAxes1,-2*Ts:Ts:(Nsim+1)*Ts)

            % Show legend
            legend(app.UIAxes1,legNames)
            
            % Set xlabel
            xlabel(app.UIAxes1,"k")
            
            % Change title
            title(app.UIAxes1,"Closed Loop Simulation - Inputs");
    
            % Stop plotting
            hold(app.UIAxes1,'off') 
        end

        % Value changed function: CLXDropDown
        function CLXDropDownValueChanged(app, event)
            % Get selected state
            selection = app.CLXDropDown.Value;
                
            % Get sample time
            Ts = app.mpcObj.model.Ts;
         
            % Clear all plots
            cla(app.UIAxes2,'reset');
                
            % Get number of states and timesteps
            [Nsim,states] = size(app.CLXRes);
                
            % Copy last values for better display
            if strcmp(app.ProblemSwitch.Value,"Tracking")
                CLXRef = [app.xRef app.xRef(:,end)];
            end            
            
            % Plot depending on number of states
            if states == 1 || selection > 0
                % Set selection to 1 if there is only 1 state
                if states == 1
                    selection = 1;
                end
                
                % Plot state
                plot(app.UIAxes2,0:Ts:(Nsim-1)*Ts,app.CLXRes(:,selection),'LineWidth',2,'Color','b');
                hold(app.UIAxes2,'on')
                
                
                if ~all(all(app.CLDist == 0))
                    % Plot state with disturbance (if applicable)
                    plot(app.UIAxes2,0:Ts:(Nsim-1)*Ts,app.CLXDistRes(:,selection),'LineWidth',2,'Color','r');
                    
                    % Plot disturbance (if applicable)
                    stairs(app.UIAxes2,0:Ts:(Nsim-1)*Ts,app.CLDist(:,selection),'LineWidth',1,'Color','k');
                    
                    % Create legend content                     
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        legNames = [append("State ",string(selection)),append("Disturbed State ",string(selection)),append("Disturbance ",string(selection)),"Present"]; 
                    else
                        legNames = [append("State ",string(selection)),append("Disturbed State ",string(selection)),append("Disturbance ",string(selection)),append("Reference ",string(selection)),"Present"]; 
                    end
                else
                    % Create legend content                     
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        legNames = [append("State ",string(selection)),"Present"]; 
                    else
                        legNames = [append("State ",string(selection)),append("Reference ",string(selection)),"Present"]; 
                    end
                end
                    
                % Plot reference as stairfunction if tracking is turned on
                if strcmp(app.ProblemSwitch.Value,"Tracking")
                    stairs(app.UIAxes2,0:Ts:(Nsim-1)*Ts,CLXRef(selection,:),'LineStyle','--','LineWidth',2);
                end
                    
                % Plot constraints
                plot(app.UIAxes2,-2*Ts:Ts:Nsim*Ts,ones(1,Nsim+3)*app.mpcObj.model.x.min(selection),'LineWidth',1,"LineStyle","-.",'Color','r','HandleVisibility','off');
                plot(app.UIAxes2,-2*Ts:Ts:Nsim*Ts,ones(1,Nsim+3)*app.mpcObj.model.x.max(selection),'LineWidth',1,"LineStyle","-.",'Color','r','HandleVisibility','off');
                                 
                 % Calculate limit for y-axis
                minimum = app.mpcObj.model.x.min(selection)-abs(app.mpcObj.model.x.min(selection))*0.25;
                maximum = app.mpcObj.model.x.max(selection)+abs(app.mpcObj.model.x.max(selection))*0.25;
                
            elseif selection == 0
                
                % Plot all states as
                plot(app.UIAxes2,0:Ts:(Nsim-1)*Ts,app.CLXRes,'LineWidth',2);
                hold(app.UIAxes2,'on')                
                
                if ~all(all(app.CLDist == 0))
                    % Plot all states with disturbances (if applicable)
                    plot(app.UIAxes2,0:Ts:(Nsim-1)*Ts,app.CLXDistRes,'LineWidth',2);
                    
                    % Plot disturbance (if applicable)
                    stairs(app.UIAxes2,0:Ts:(Nsim-1)*Ts,app.CLDist,'LineWidth',1);
                    
                    % Set legend names
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        for i=1:states
                            legNames(i) = append("State ",string(i)); 
                            legNames(states+i) = append("Disturbed State ",string(i));
                            legNames(2*states+i) = append("Disturbance ",string(i));
                        end
                        legNames(3*states+1) = "Present";
                    else
                        for i=1:states
                            legNames(i) = append("State ",string(i));
                            legNames(states+i) = append("Disturbed State ",string(i));
                            legNames(2*states+i) = append("Disturbance ",string(i));
                            legNames(3*states+i) = append("Reference ",string(i));
                        end
                        legNames(4*states+1) = "Present";                         
                    end
                else
                    % Set legend names
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        for i=1:states
                            legNames(i) = append("State ",string(i)); 
                        end
                        legNames(states+1) = "Present";
                    else
                        for i=1:states
                            legNames(i) = append("State ",string(i));
                            legNames(states+i) = append("Reference ",string(i));
                        end
                        legNames(2*states+1) = "Present";                         
                    end
                end
                    
                % Plot references if tracking is turned on
                if strcmp(app.ProblemSwitch.Value,"Tracking")
                    stairs(app.UIAxes2,0:Ts:(Nsim-1)*Ts,CLXRef','LineStyle','--','LineWidth',2);
                    
                    % Calculate limits for y-axis                
                    minY = min([min(min(app.CLXRes)),min(min(app.CLXDistRes)),min(min(app.CLDist)),min(min(app.xRef))]);
                    maxY = max([max(max(app.CLXRes)),max(max(app.CLXDistRes)),max(max(app.CLDist)),max(max(app.xRef))]);
                else
                    % Calculate limits for y-axis                
                    minY = min([min(min(app.CLXRes)),min(min(app.CLXDistRes)),min(min(app.CLDist))]);
                    maxY = max([max(max(app.CLXRes)),max(max(app.CLXDistRes)),max(max(app.CLDist))]);
                end         
                
                % Calculate limits for y-axis  
                minimum = minY-(abs(maxY)+abs(minY))*0.15;
                maximum = maxY+(abs(maxY)+abs(minY))*0.25; 
                            
            end
                
            % Plot vertical line indicating present
            xl = xline(app.UIAxes2,0,'-',{'  \leftarrow Past Future \rightarrow'},'LineWidth',1.5);
            xl.LabelHorizontalAlignment = 'center';
            xl.LabelVerticalAlignment = 'top';
            xl.LabelOrientation = 'horizontal';    
                
            % Set limit for x-axis
            xlim(app.UIAxes2,[-2*Ts Nsim*Ts])
                
            if minimum == 0 && maximum == 0              
                    minimum = -1;
                    maximum = 1;
            end
            
            % Set limit for y-axis
            ylim(app.UIAxes2,[minimum maximum])

            % Set x-ticks so that Ts is distance between them
            xticks(app.UIAxes2,-2*Ts:Ts:Nsim*Ts)

            % Show legend
            legend(app.UIAxes2,legNames)
            
            % Set xlabel
            xlabel(app.UIAxes2,"k")
            
            % Change title
            title(app.UIAxes2,"Closed Loop Simulation - States");

            % Stop plotting
            hold(app.UIAxes2,'off')
        end

        % Button pushed function: CLNewSimulationButton
        function CLNewSimulationButtonPushed(app, event)
            % Activate selection and input elements
            app.ProblemSwitch.Enable = "on";
            app.CLTable.Enable = "on";
            if strcmp(app.ProblemSwitch.Value,"Tracking")
                app.ReferenceTrajectoryButton.Enable = "on";
            end
            
            % Reset results
            app.CLURes = [];
            app.CLXRes = [];
            app.CLUDistRes = [];
            app.CLXDistRes = [];
            app.CLDist = [];
            app.CLXInitial = [];
            
            % Reset initial states
            Nx = app.mpcObj.nx;
            CLData = cell(1,Nx);
            CLData(1,:) = {nan};
            app.CLTable.Data = CLData;
            app.CLXInitial = NaN(1,Nx);
                
            % Reset dropdown menus
            app.CLUDropDown.Value = 0;
            app.CLXDropDown.Value = 0;
            app.CLMethodDropDown.Value = "Time";
                
            % Deactivate elements for step- and displaychanges
            app.CLUDropDown.Enable = 'off';
            app.CLXDropDown.Enable = 'off';
            app.CLMethodDropDown.Enable = 'off'; 
            app.CLNewSimulationButton.Enable = 'off';
        end

        % Button pushed function: EXRegionSimulateButton
        function EXRegionSimulateButtonPushed(app, event)
            % Get copy of mpc object
            mpc = app.mpcObj;
                              
            if strcmp(app.ProblemSwitch.Value,"Regulation") && app.mpcObj.nx == 2
                % Clear axes
                cla(app.UIAxes1,"reset")
                cla(app.UIAxes2,"reset")
                
                % Generate explicit controller
                expMpc = mpc.toExplicit();
                
                % Get explicit solution in partition form
                app.EXPartitions = expMpc.partition;
                expMpc.partition.plot()
                
                % Save figure and axes
                figExp = gcf;
                axExp = gca;
                   
                % Copy axes content into GUI axes
                copyobj(get(axExp,'Children'),app.UIAxes1);
    
                % Take over x- and y-ticks
                app.UIAxes1.XTick = axExp.XTick;
                app.UIAxes1.YTick = axExp.YTick;
                
                % Take over aspect ratio
                app.UIAxes1.PlotBoxAspectRatio = axExp.PlotBoxAspectRatio;
                
                % Copy axes content into GUI axes
                copyobj(get(axExp,'Children'),app.UIAxes2);
    
                % Take over x- and y-ticks
                app.UIAxes2.XTick = axExp.XTick;
                app.UIAxes2.YTick = axExp.YTick;
                
                % Take over aspect ratio
                app.UIAxes2.PlotBoxAspectRatio = axExp.PlotBoxAspectRatio;
                
                % Change title
                title(app.UIAxes1,"Explicit Solution")
                title(app.UIAxes2,"Explicit Regions")
                
                % Set x- and y-label
                xlabel(app.UIAxes1,"x1")
                xlabel(app.UIAxes2,"x1")
                ylabel(app.UIAxes1,"x2")
                ylabel(app.UIAxes2,"x2")
                    
                % Close figure after copying
                delete(figExp)               
                    
                % Get explicit optimizer
                optimizer = expMpc.optimizer;
    
                % Get values for each partition
                for i = 1:optimizer.Num
                        app.EXK{i} = optimizer.Set(i).Functions('primal').F;
                        app.EXb{i} = optimizer.Set(i).Functions('primal').g;
                end    
                    
                % Set limits for spinner
                app.EXRegionSpinner.Limits = [1 length(app.EXPartitions.Set)];
                    
                % Call spinner for value 1
                app.EXRegionSpinner.Value = 1;
                EXRegionSpinnerValueChanged(app); 
                
                % Activate region spinner
                app.EXRegionSpinner.Enable = 'on';
                
                % Activate coefficient tables
                app.EXKTable.Enable = 'on';
                app.EXbTable.Enable = 'on';
                
                % Deactivate simulation button
                app.EXRegionSimulateButton.Enable = "off";
                
                % Activate new simulation button
                app.EXNewSimulationButton.Enable = "on";
            else
                uialert(app.UIFigure,"State-space display is only available for a regulation problem with two states!","State-space representation not available!","Icon","warning");            
            end
        end

        % Value changed function: EXRegionSpinner
        function EXRegionSpinnerValueChanged(app, event)
            % Get current partitionnumber           
            num = app.EXRegionSpinner.Value;                
                
            % Find all polyhedron handles and delete them
            poly = findobj(app.UIAxes2,'LineWidth',3);
            delete(poly);
                
                
            % Get coefficients A and b of current partition
            A = app.EXPartitions.Set(num,1).A;
            b = app.EXPartitions.Set(num,1).b;
            
            % Create polyhedron with coeffizients
            P = Polyhedron('A',A,'b',b);                

            % Plot polyhedron as wire
            P.plot('wire',true,'linewidth',3)
                        
            % Save figure and axes
            figPart = gcf;
            axPart = gca;
           
            % Copy axes content into GUI axes
            copyobj(get(axPart,'Children'),app.UIAxes2);
            
            % Delete figure
            delete(figPart)
                
            % Show coefficients of current partition in tables
            app.EXKTable.Data = table(app.EXK{1,num});
            app.EXbTable.Data = table(app.EXb{1,num}); 
        end

        % Button pushed function: EXNewSimulationButton
        function EXNewSimulationButtonPushed(app, event)
            % Turn on the simulation button
            app.EXRegionSimulateButton.Enable = "on";
            
            % Turn off the regiontables
            app.EXRegionSpinner.Enable = "off";
            app.EXNewSimulationButton.Enable = "off";
                        
            % Reset results
            app.EXPartitions = [];          
            app.EXK = [];               
            app.EXb = [];  
            app.EXKTable.Data = [];
            app.EXbTable.Data = [];
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Get selected tab
            selectedTab = app.TabGroup.SelectedTab.Title;
            
            % Check if simulation side should be enabled
            if app.config(10)
                % Reset simulation elements according to selection
                switch(selectedTab)
                    case "Open loop simulation"
                        OLNewSimulationButtonPushed(app);
                    case "Closed loop simlation"
                        CLNewSimulationButtonPushed(app);
                    case "Plant-model-mismatch"
                        PMMNewSimulationButtonPushed(app);
                    case "Explicit solution"
                        EXNewSimulationButtonPushed(app);
                end    
            end
        end

        % Cell edit callback: PMMTable
        function PMMTableCellEdit(app, event)
            % Get column of entered data
            indices = event.Indices;
            column = indices(2);
            
            % Get entered data
            data = event.NewData;
            
            % Check whether input is valid
            if isreal(data) && isscalar(data) && ~isempty(data) && ~isnan(data) && data >= app.mpcObj.model.x.min(column) && data <= app.mpcObj.model.x.max(column)
                % Update field in inital state array
                app.PMMXInitial(column) = data;
            else
                % Show last valid value if input is invalid
                app.PMMTable.Data(1,column) = {app.PMMXInitial(column)};
            end

            % Check if all fields have been filled
            if all(~isnan(app.PMMXInitial))
                % Enable simulation elements
                app.PMMInputButton.Enable = "on";
                app.PMMSimulateButton.Enable = "on";
                
                % Get selection
                selection = app.ProblemSwitch.Value;
                
                % Check whether a Tracking or Regulation problem was selcted
                if strcmp(selection,"Regulation")
                    app.PMMNsimEditField.Enable = 'on';
                elseif strcmp(selection,"Tracking")
                    app.PMMNsimEditField.Enable = 'off';
                end
            end
        end

        % Button pushed function: PMMInputButton
        function PMMInputButtonPushed(app, event)
            % Call GUI for plant input
            PlantGUI(app,app.mpcObj,app.PlantModel)
        end

        % Button pushed function: PMMSimulateButton
        function PMMSimulateButtonPushed(app, event)
            % Check whether all fields have been filled
            if all(~isnan(app.PMMXInitial))
                try                
                    % Deactivate buttons for input
                    app.ProblemSwitch.Enable = 'off';
                    app.PMMTable.Enable = 'off';
                    app.PMMInputButton.Enable = 'off';
                    app.PMMNsimEditField.Enable = 'off';
                    app.PMMSimulateButton.Enable = 'off';
                    
                    % Prepare closed loop simulation for the normal model
                    model = LTISystem('A',app.mpcObj.model.A,'B',app.mpcObj.model.B);
                    loop = ClosedLoop(app.mpcObj,model);
                    
                    % Get timesteps 
                    Nsim = app.PMMNsimEditField.Value;
                    
                    % Get problem selection
                    selection = app.ProblemSwitch.Value;
                        
                    % Make closed loop simulation and get data
                    if strcmp(selection,"Regulation")                    
                        results = loop.simulate(app.PMMXInitial',Nsim);
                    else
                        results = loop.simulate(app.PMMXInitial',Nsim,'x.reference',app.xRef);
                    end
                    
                    % Save results
                    app.MURes = results.U';
                    app.MXRes = results.X';
                    app.MCostRes = results.cost;
                    
                    % Prepare closed loop simulation for the plant model
                    if isempty(app.PlantModel)
                        app.PlantModel = model;
                    end                   
                    loop = ClosedLoop(app.mpcObj,app.PlantModel);
                    
                    % Make closed loop simulation and get data
                    if strcmp(selection,"Regulation")                    
                        results = loop.simulate(app.PMMXInitial',Nsim);
                        assignin("base","results",results)
                    else
                        results = loop.simulate(app.PMMXInitial',Nsim,'x.reference',app.xRef);
                    end
                    
                    % Save results
                    app.PURes = results.U';
                    app.PXRes = results.X';
                    app.PCostRest = results.cost;
                    
                    % Plot only if the simulation was successfull
                    Nsim = length(app.PURes);                    
                    if Nsim == app.PMMNsimEditField.Value
                        % Plot results
                        PMMUDropDownValueChanged(app);
                        PMMMethodDropDownValueChanged(app);
                        
                        % Activate buttons for displaychanges
                        app.PMMUDropDown.Enable = 'on';
                        app.PMMMethodDropDown.Enable = 'on';
                        app.PMMNewSimulationButton.Enable = 'on';
                    else
                        uialert(app.UIFigure,"Simulation not feasible for this configuration!","Simulation infeasible!","Icon","warning");
                        PMMNewSimulationButtonPushed(app)
                    end
        
                catch
                    % Inform user of non successfull simulation
                    uialert(app.UIFigure,"Closed loop simulation unsuccessfull","Simulation unsuccessfull","Icon","warning");
                    
                    % Activate input elements
                    PMMNewSimulationButtonPushed(app);
                end
            end
        end

        % Value changed function: PMMMethodDropDown
        function PMMMethodDropDownValueChanged(app, event)
            % Get selected displaymethod
            method = app.PMMMethodDropDown.Value;
            
            % Check if plant and model are identical
            if all(all(app.mpcObj.model.A == app.PlantModel.A)) && all(all(app.mpcObj.model.B == app.PlantModel.B))
                identical = true;
            else
                identical = false;
            end
            
            if strcmp(method,"Time")
                % Turn on dropdown for state selection
                app.PMMUDropDown.Enable = "on";
                app.PMMXDropDown.Enable = "on";
                
                % Call PMMX DropDownChange to display results over time
                PMMXDropDownValueChanged(app);
                
            elseif strcmp(method,"State-Space")
                % Check if model is compatible with state-space display
                if app.mpcObj.nx == 2 && strcmp(app.ProblemSwitch.Value,"Regulation")
                    
                    % Clear axes
                    cla(app.UIAxes2,"reset")
                    
                    % Turn off dropdown for state selection
                    app.PMMUDropDown.Enable = "on";
                    app.PMMXDropDown.Enable = "off";
                    
                    % Get copy of mpc object
                    mpc = app.mpcObj;
                    
                    % Generate explicit controller
                    expMpc = mpc.toExplicit();
                    
                    % Get explicit partitions
                    expMpc.partition.plot()
                    
                    % Save figure and axes
                    figExp = gcf;
                    axExp = gca;
                    
                    % Copy axes content into GUI axes
                    copyobj(get(axExp,'Children'),app.UIAxes2);
                    
                    % Take over x- and y-ticks
                    app.UIAxes2.XTick = axExp.XTick;
                    app.UIAxes2.YTick = axExp.YTick;
                    
                    % Take over aspect ratio
                    app.UIAxes2.PlotBoxAspectRatio = axExp.PlotBoxAspectRatio;
                    
                    % Close figure after copying
                    delete(figExp);
                    
                    % Wait for other plots
                    hold(app.UIAxes2,'on')
                    
                    % Find all polyhedron handles and set handlevisibility off
                    poly = findobj(app.UIAxes2,'LineWidth',1);
                    for i=1:length(poly)
                        poly(i,1).HandleVisibility = 'off';
                    end
                    
                    % Plot modelstates in state-space
                    plot(app.UIAxes2,app.MXRes(:,1),app.MXRes(:,2),"LineWidth",2,'Color','k','Marker','x','MarkerSize',8); 

                    % Plot plantstates in state-space if there's a mismatch
                    if ~identical
                        plot(app.UIAxes2,app.PXRes(:,1),app.PXRes(:,2),"LineWidth",2,'Color',[0.8 0.8 0.8],'Marker','x','MarkerSize',8); 
                        
                        % Set legend names
                        legend(app.UIAxes2,"Modelstates","Plantstates")
                    else
                        % Set legend names
                        legend(app.UIAxes2,"Modelstates")
                    end
                    
                    % Change title
                    title(app.UIAxes2,"Closed Loop Simulation - States"); 
                    
                    % Set x- and y-label
                    xlabel(app.UIAxes2,"x1")
                    ylabel(app.UIAxes2,"x2")
                else
                   % Reset the DropDown Menu
                   app.CLMethodDropDown.Value = "Time";
                   uialert(app.UIFigure,"State-space display is only available for a regulation problem with two states!","State-space representation not available!","Icon","warning");
                end
            else
                % Clear axes
                cla(app.UIAxes2,"reset")
                
                % Turn on dropdown for state selection
                app.PMMUDropDown.Enable = "off";
                app.PMMXDropDown.Enable = "off";
                
                % Get count of simulation steps
                Nsim = length(app.MCostRes);
                
                % Plot costfunction of the model
                Ts = 1;
                plot(app.UIAxes2,0:Ts:(Nsim-1)*Ts,app.MCostRes,'LineWidth',2,"Color","b");                
                hold(app.UIAxes2,"on")
                
                % Plot costfunction of the plant it there's a mismatch
                if ~identical
                    plot(app.UIAxes2,0:1:(Nsim-1),app.PCostRest,'LineWidth',2,"Color","r");
                    
                    % Set legend names
                    legNames = ["Costfunction of model","Costfunction of plant","Present"]; 
                else
                    % Set legend names
                    legNames = ["Costfunction of model","Present"];
                    
                end
                
                % Plot vertical line indicating present
                xl = xline(app.UIAxes2,0,'-',{'  \leftarrow Past Future \rightarrow'},'LineWidth',1.5);
                xl.LabelHorizontalAlignment = 'center';
                xl.LabelVerticalAlignment = 'top';
                xl.LabelOrientation = 'horizontal';    
                    
                % Set limit for x-axis
                xlim(app.UIAxes2,[-2*Ts Nsim*Ts])
                    
                % Calculate limits for y-axis                
                minY = min([min(min(app.MCostRes)),min(min(app.PCostRest))]);
                maxY = max([max(max(app.MCostRes)),max(max(app.PCostRest))]);
                        
                
                % Calculate limits for y-axis  
                minimum = minY-(abs(maxY)+abs(minY))*0.15;
                maximum = maxY+(abs(maxY)+abs(minY))*0.25;
                
                % Set limit for y-axis
                if minimum == 0 && maximum == 0              
                    minimum = -1;
                    maximum = 1;
                end
                ylim(app.UIAxes2,[minimum maximum])               
    
                % Set x-ticks so that Ts is distance between them
                xticks(app.UIAxes2,-2*Ts:Ts:Nsim*Ts)
    
                % Show legend
                legend(app.UIAxes2,legNames)
                
                % Change title
                title(app.UIAxes2,"Closed Loop Simulation - Costfunction");
    
                % Set xlabel
                xlabel(app.UIAxes2,"k")
                
                % Stop plotting
                hold(app.UIAxes2,'off')
            end
 
        end

        % Value changed function: PMMUDropDown
        function PMMUDropDownValueChanged(app, event)
            % Get selected input
            selection = app.CLUDropDown.Value;
            
            % Get sample time
            Ts = app.mpcObj.model.Ts;
            
            % Clear all plots
            cla(app.UIAxes1,'reset');
            
            % Get number of inputs and timesteps
            [Nsim,inputs] = size(app.MURes);
            
            % Check if plant and model are identical
            if all(all(app.mpcObj.model.A == app.PlantModel.A)) && all(all(app.mpcObj.model.B == app.PlantModel.B))
                identical = true;
            else
                identical = false;
            end
            
            % Plot depending on number of inputs
            if inputs == 1 || selection > 0
                % Set selection to 1 if there is only 1 input
                if inputs == 1
                    selection = 1;
                end
                
                % Plot modelinput as stairfunction
                stairs(app.UIAxes1,0:Ts:(Nsim-1)*Ts,app.MURes(:,selection),'LineWidth',2,'Color','b');
                hold(app.UIAxes1,'on');
                
                % Plot plantinput as stairfunction if there's a mismatch
                if ~identical
                    stairs(app.UIAxes1,0:Ts:(Nsim-1)*Ts,app.PURes(:,selection),'LineWidth',2,'Color','r');
                    
                    % Set legend names
                    legNames = [append("Modelinput ",string(selection)),append("Plantinput ",string(selection)),"Present"];
                else
                    % Set legend names
                    legNames = [append("Modelinput ",string(selection)),"Present"];
                end
                
                % Plot constraints
                plot(app.UIAxes1,-2*Ts:Ts:(Nsim+1)*Ts,ones(1,Nsim+4)*app.mpcObj.model.u.min(selection),'LineWidth',1,"LineStyle","-.",'Color','r','HandleVisibility','off');
                plot(app.UIAxes1,-2*Ts:Ts:(Nsim+1)*Ts,ones(1,Nsim+4)*app.mpcObj.model.u.max(selection),'LineWidth',1,"LineStyle","-.",'Color','r','HandleVisibility','off');
                     
                % Calculate limit for y-axis
                minimum = app.mpcObj.model.u.min(selection)-abs(app.mpcObj.model.u.min(selection))*0.25;
                maximum = app.mpcObj.model.u.max(selection)+abs(app.mpcObj.model.u.max(selection))*0.25;
                
            elseif selection == 0
                % Plot all modelinputs as stairfunctions
                stairs(app.UIAxes1,0:Ts:(Nsim-1)*Ts,app.MURes,'LineWidth',2);
                hold(app.UIAxes1,'on')
                
                % Plot all plantinputs as stairfunctions if there's a
                % mismatch
                if ~identical
                    stairs(app.UIAxes1,0:Ts:(Nsim-1),app.PURes,'LineWidth',2)
                    
                    % Set legend names
                    for i=1:inputs
                        legNames(i) = append("Modelinput ",string(i));
                        legNames(inputs+i) = append("Plantinput ",string(i));
                    end                
                    legNames(inputs*2+1) = "Present";
                else
                    % Set legend names
                    for i=1:inputs
                        legNames(i) = append("Modelinput ",string(i));
                    end                
                    legNames(inputs+1) = "Present";
                end
                        
                % Calculate limit for y-axis
                minY = min(min(app.CLURes));
                maxY = max(max(app.CLURes));
                
                minimum = minY-(abs(maxY)+abs(minY))*0.15;
                maximum = maxY+(abs(maxY)+abs(minY))*0.25; 
               
            end
                
            % Plot vertical line to indicate present
            xl = xline(app.UIAxes1,0,'-',{'  \leftarrow Past Future \rightarrow'},'LineWidth',1.5);
            xl.LabelHorizontalAlignment = 'center';
            xl.LabelVerticalAlignment = 'top';
            xl.LabelOrientation = 'horizontal';    
                
            % Set limit for x-axis
            xlim(app.UIAxes1,[-2*Ts (Nsim+1)*Ts])
            
            if minimum == 0 && maximum == 0              
                minimum = -1;
                maximum = 1;
            end
            
            % Set limit for y-axis
            ylim(app.UIAxes1,[minimum maximum])                          
                
            % Set x-ticks so that Ts is distance between them
            xticks(app.UIAxes1,-2*Ts:Ts:(Nsim+1)*Ts)

            % Show legend
            legend(app.UIAxes1,legNames)
            
            % Set xlabel
            xlabel(app.UIAxes1,"k")
            
            % Change title
            title(app.UIAxes1,"Closed Loop Simulation - Inputs");
    
            % Stop plotting
            hold(app.UIAxes1,'off')
        end

        % Value changed function: PMMXDropDown
        function PMMXDropDownValueChanged(app, event)
            % Get selected state
            selection = app.PMMXDropDown.Value;
                
            % Get sample time
            Ts = app.mpcObj.model.Ts;
         
            % Clear all plots
            cla(app.UIAxes2,'reset');
                
            % Get number of states and timesteps
            [Nsim,states] = size(app.MXRes);
            
            % Check if plant and model are identical
            if all(all(app.mpcObj.model.A == app.PlantModel.A)) && all(all(app.mpcObj.model.B == app.PlantModel.B))
                identical = true;
            else
                identical = false;
            end
                
            % Copy last values for better display
            if strcmp(app.ProblemSwitch.Value,"Tracking")
                PMMXRef = [app.xRef app.xRef(:,end)];
            end            
            
            % Plot depending on number of states
            if states == 1 || selection > 0
                % Set selection to 1 if there is only 1 state
                if states == 1
                    selection = 1;
                end
                
                % Plot selected modelstate
                plot(app.UIAxes2,0:Ts:(Nsim-1)*Ts,app.MXRes(:,selection),'LineWidth',2,'Color','b');
                hold(app.UIAxes2,'on')
                
                % Plot selected plantstate if there's a mismatch
                if ~identical
                    plot(app.UIAxes2,0:Ts:(Nsim-1)*Ts,app.PXRes(:,selection),'LineWidth',2,'Color','r');
                    
                    % Set legend names
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        legNames = [append("Modelstate ",string(selection)),append("Plantstate ",string(selection)),"Present"];
                    else
                        legNames = [append("Modelstate ",string(selection)),append("Plantstate ",string(selection)),append("Reference ",string(selection)),"Present"];
                    end
                else
                    % Set legend names
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        legNames = [append("Modelstate ",string(selection)),"Present"];
                    else
                        legNames = [append("Modelstate ",string(selection)),append("Reference ",string(selection)),"Present"];
                    end
                end
   
                % Plot reference as stairfunction if tracking is turned on
                if strcmp(app.ProblemSwitch.Value,"Tracking")
                    stairs(app.UIAxes2,0:Ts:(Nsim-1)*Ts,PMMXRef(selection,:),'LineStyle','--','LineWidth',2);
                end
                    
                % Plot constraints
                plot(app.UIAxes2,-2*Ts:Ts:Nsim*Ts,ones(1,Nsim+3)*app.mpcObj.model.x.min(selection),'LineWidth',1,"LineStyle","-.",'Color','r','HandleVisibility','off');
                plot(app.UIAxes2,-2*Ts:Ts:Nsim*Ts,ones(1,Nsim+3)*app.mpcObj.model.x.max(selection),'LineWidth',1,"LineStyle","-.",'Color','r','HandleVisibility','off');
                                 
                 % Calculate limit for y-axis
                minimum = app.mpcObj.model.x.min(selection)-abs(app.mpcObj.model.x.min(selection))*0.25;
                maximum = app.mpcObj.model.x.max(selection)+abs(app.mpcObj.model.x.max(selection))*0.25;
                
            elseif selection == 0
                
                % Plot all modelstates
                plot(app.UIAxes2,0:Ts:(Nsim-1)*Ts,app.MXRes,'LineWidth',2);
                hold(app.UIAxes2,'on')                
                
                % Plot all plantstates if there's a mismatch
                if ~identical
                    plot(app.UIAxes2,0:Ts:(Nsim-1)*Ts,app.PXRes,'LineWidth',2);
                    
                    % Set legend names
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        for i=1:states
                            legNames(i) = append("Modelstate ",string(i)); 
                            legNames(states+i) = append("Plantstate ",string(i));
                        end
                        legNames(2*states+1) = "Present";
                    else
                        for i=1:states
                            legNames(i) = append("Modelstate ",string(i));
                            legNames(states+i) = append("Plantstate ",string(i));
                            legNames(2*states+i) = append("Reference ",string(i));
                        end
                        legNames(3*states+1) = "Present";                         
                    end
                else
                    % Set legend names
                    if strcmp(app.ProblemSwitch.Value,"Regulation")
                        for i=1:states
                            legNames(i) = append("Modelstate ",string(i)); 
                        end
                        legNames(states+1) = "Present";
                    else
                        for i=1:states
                            legNames(i) = append("Modelstate ",string(i));
                            legNames(states+i) = append("Reference ",string(i));
                        end
                        legNames(2*states+1) = "Present";                         
                    end
                end
                
                    
                % Plot references if tracking is turned on
                if strcmp(app.ProblemSwitch.Value,"Tracking")
                    stairs(app.UIAxes2,0:Ts:(Nsim-1)*Ts,PMMXRef','LineStyle','--','LineWidth',2);
                    
                    % Calculate limits for y-axis                
                    minY = min([min(min(app.MXRes)),min(min(app.PXRes)),min(min(app.xRef))]);
                    maxY = max([max(max(app.MXRes)),max(max(app.PXRes)),max(max(app.xRef))]);
                else
                    % Calculate limits for y-axis                
                    minY = min([min(min(app.MXRes)),min(min(app.PXRes))]);
                    maxY = max([max(max(app.MXRes)),max(max(app.PXRes))]);
                end         
                
                % Calculate limits for y-axis  
                minimum = minY-(abs(maxY)+abs(minY))*0.15;
                maximum = maxY+(abs(maxY)+abs(minY))*0.25; 
                            
            end
                
            % Plot vertical line indicating present
            xl = xline(app.UIAxes2,0,'-',{'  \leftarrow Past Future \rightarrow'},'LineWidth',1.5);
            xl.LabelHorizontalAlignment = 'center';
            xl.LabelVerticalAlignment = 'top';
            xl.LabelOrientation = 'horizontal';    
                
            % Set limit for x-axis
            xlim(app.UIAxes2,[-2*Ts Nsim*Ts])
                
            if minimum == 0 && maximum == 0              
                minimum = -1;
                maximum = 1;
            end
            
            % Set limit for y-axis
            ylim(app.UIAxes2,[minimum maximum])

            % Set x-ticks so that Ts is distance between them
            xticks(app.UIAxes2,-2*Ts:Ts:Nsim*Ts)

            % Show legend
            legend(app.UIAxes2,legNames)
            
            % Set xlabel
            xlabel(app.UIAxes2,"k")
            
            % Change title
            title(app.UIAxes2,"Closed Loop Simulation - States");

            % Stop plotting
            hold(app.UIAxes2,'off')
        end

        % Button pushed function: PMMNewSimulationButton
        function PMMNewSimulationButtonPushed(app, event)
            % Activate selection and input elements
            app.ProblemSwitch.Enable = "on";
            app.PMMTable.Enable = "on";
            if strcmp(app.ProblemSwitch.Value,"Tracking")
                app.ReferenceTrajectoryButton.Enable = "on";
            end
            
            % Reset results
            app.MURes = [];
            app.MXRes = [];
            app.PURes = [];
            app.PXRes = [];
            app.MCostRes = [];
            app.PCostRest = [];
            
            % Reset initial states
            Nx = app.mpcObj.nx;
            PMMData = cell(1,Nx);
            PMMData(1,:) = {nan};
            app.PMMTable.Data = PMMData;
            app.PMMXInitial = NaN(1,Nx);
            
            % Reset dropdown menus
            app.PMMUDropDown.Value = 0;
            app.PMMXDropDown.Value = 0;
            app.PMMMethodDropDown.Value = "Time";
            
            % Deactivate elements for step- and displaychanges
            app.PMMUDropDown.Enable = "off";
            app.PMMXDropDown.Enable = "off";
            app.PMMMethodDropDown.Enable = "off";
            app.PMMNewSimulationButton.Enable = "off";
        end

        % Menu selected function: ResetGUIMenu
        function ResetGUIMenuSelected(app, event)
            % Reasure the user wants to reset the GUI
            fig = app.UIFigure;
            selection = uiconfirm(fig,'Do you really want to reset the GUI?','Reset GUI','Icon','warning');
            
            if strcmp(selection,"OK")
                if ~isempty(app.mpcObj.model.A)
                    % Create empty LTI system
                    model = LTISystem();
                    
                    % Create empty MPC Object
                    app.mpcObj = MPCController(model,1);
                    
                    % Update horizon
                    app.HorizonEditField.Value = 1;
                    
                    % Enable initial elements
                    app.config = [1,0,0,0,0,0,0,0,0,0];
                    elements(app);
    
                    % Reset summary
                    app.NuNxLabel.Text = "0/0";
                    app.ConstraintsLabel.Text = "0/0";
                    app.PenaltiesLabel.Text = "N/A";
                    app.TerminalLabel.Text = "No";
                    app.HorizonLabel.Text = "1";
                    
                    % Reset simulation results
                    OLNewSimulationButtonPushed(app);
                    CLNewSimulationButtonPushed(app);
                    PMMNewSimulationButtonPushed(app);
                    EXNewSimulationButtonPushed(app);
                    
                    % Reset simulation side
                    resetSim(app)
                else
                    uialert(app.UIFigure,"GUI is already reset!","GUI reset","Icon","warning");
                end
            end        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SaveMPCMenu = uimenu(app.Menu);
            app.SaveMPCMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMPCMenuSelected, true);
            app.SaveMPCMenu.Text = 'Save MPC';
            app.LoadMPCMenu = uimenu(app.Menu);
            app.LoadMPCMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMPCMenuSelected, true);
            app.LoadMPCMenu.Text = 'Load MPC';
            app.ResetGUIMenu = uimenu(app.Menu);
            app.ResetGUIMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetGUIMenuSelected, true);
            app.ResetGUIMenu.Text = 'Reset GUI';
            app.InputPanel = uipanel(app.UIFigure);
            app.DefinesystemmodelPanel = uipanel(app.InputPanel);
            app.DefinesystemmodelPanel.Title = '1. Define system model';
            app.SystemModelButton = uibutton(app.DefinesystemmodelPanel, 'push');
            app.SystemModelButton.ButtonPushedFcn = createCallbackFcn(app, @SystemModelButtonPushed, true);
            app.SystemModelButton.Tooltip = {'Open GUI for entering the systemmodel in state-space form'};
            app.SystemModelButton.Text = 'Enter model';
            app.SystemModelLamp = uilamp(app.DefinesystemmodelPanel);
            app.AddcontraintsPanel = uipanel(app.InputPanel);
            app.AddcontraintsPanel.Title = '2. Add contraints';
            app.ConstraintsButton = uibutton(app.AddcontraintsPanel, 'push');
            app.ConstraintsButton.ButtonPushedFcn = createCallbackFcn(app, @ConstraintsButtonPushed, true);
            app.ConstraintsButton.Enable = 'off';
            app.ConstraintsButton.Tooltip = {'Open GUI for entering input and state constraints'};
            app.ConstraintsButton.Text = 'Add contraints';
            app.ConstraintsLamp = uilamp(app.AddcontraintsPanel);
            app.ConstraintsLamp.Tag = 'Lamp';
            app.ConstraintsLamp.Enable = 'off';
            app.DefinepenaltiesPanel = uipanel(app.InputPanel);
            app.DefinepenaltiesPanel.Title = '3. Define penalties';
            app.PenaltiesButton = uibutton(app.DefinepenaltiesPanel, 'push');
            app.PenaltiesButton.ButtonPushedFcn = createCallbackFcn(app, @PenaltiesButtonPushed, true);
            app.PenaltiesButton.Enable = 'off';
            app.PenaltiesButton.Tooltip = {'Open GUI for entering input and state penalties'};
            app.PenaltiesButton.Text = 'Enter penalties';
            app.PenaltiesLamp = uilamp(app.DefinepenaltiesPanel);
            app.PenaltiesLamp.Tag = 'Lamp';
            app.PenaltiesLamp.Enable = 'off';
            app.TSetPenaltyButtonGroup = uibuttongroup(app.InputPanel);
            app.TSetPenaltyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TSetPenaltyButtonGroupSelectionChanged, true);
            app.TSetPenaltyButtonGroup.Title = '4. Define terminal set and penalty';
            app.NotsetandtpenaltyButton = uiradiobutton(app.TSetPenaltyButtonGroup);
            app.NotsetandtpenaltyButton.Enable = 'off';
            app.NotsetandtpenaltyButton.Tooltip = {'Don''t calculate terminal set and terminal penalty'};
            app.NotsetandtpenaltyButton.Text = 'No t-set and t-penalty';
            app.NotsetandtpenaltyButton.Value = true;
            app.ComputetsetandtpenaltyButton = uiradiobutton(app.TSetPenaltyButtonGroup);
            app.ComputetsetandtpenaltyButton.Enable = 'off';
            app.ComputetsetandtpenaltyButton.Tooltip = {'Calculate terminal set and penalty'};
            app.ComputetsetandtpenaltyButton.Text = 'Compute t-set and t-penalty';
            app.TSetPenaltyLamp = uilamp(app.TSetPenaltyButtonGroup);
            app.TSetPenaltyLamp.Tag = 'Lamp';
            app.TSetPenaltyLamp.Enable = 'off';
            app.ChoosepredictionhorizonPanel = uipanel(app.InputPanel);
            app.ChoosepredictionhorizonPanel.Title = '5. Choose prediction horizon';
            app.HorizonEditField = uieditfield(app.ChoosepredictionhorizonPanel, 'numeric');
            app.HorizonEditField.Limits = [1 Inf];
            app.HorizonEditField.RoundFractionalValues = 'on';
            app.HorizonEditField.ValueDisplayFormat = '%.0f';
            app.HorizonEditField.ValueChangedFcn = createCallbackFcn(app, @HorizonEditFieldValueChanged, true);
            app.HorizonEditField.Enable = 'off';
            app.HorizonEditField.Tooltip = {'Enter the prediction horizon'};
            app.HorizonEditField.Value = 1;
            app.HorizonLamp = uilamp(app.ChoosepredictionhorizonPanel);
            app.HorizonLamp.Tag = 'Lamp';
            app.HorizonLamp.Enable = 'off';
            app.SummaryPanel = uipanel(app.InputPanel);
            app.SummaryPanel.Title = '6. Summary';
            app.PenaltiesLabel = uilabel(app.SummaryPanel);
            app.PenaltiesLabel.Text = 'N/A';
            app.PenaltiesLabel2 = uilabel(app.SummaryPanel);
            app.PenaltiesLabel2.Text = 'Penalties';
            app.NumberofconstraintsinputsstatesLabel = uilabel(app.SummaryPanel);
            app.NumberofconstraintsinputsstatesLabel.Text = 'Number of constraints inputs/states';
            app.ConstraintsLabel = uilabel(app.SummaryPanel);
            app.ConstraintsLabel.Text = '0/0';
            app.NuNxLabel = uilabel(app.SummaryPanel);
            app.NuNxLabel.Text = '0/0';
            app.NumberofinputsstatesLabel = uilabel(app.SummaryPanel);
            app.NumberofinputsstatesLabel.Text = 'Number of inputs/states';
            app.TerminalsetpenaltyLabel = uilabel(app.SummaryPanel);
            app.TerminalsetpenaltyLabel.Text = 'Terminal set/penalty';
            app.TerminalLabel = uilabel(app.SummaryPanel);
            app.TerminalLabel.Text = 'No';
            app.HorizonLabel = uilabel(app.SummaryPanel);
            app.HorizonLabel.Text = '1';
            app.PredictionhorizonLabel = uilabel(app.SummaryPanel);
            app.PredictionhorizonLabel.Text = 'Prediction horizon';
            app.SimulationPanel = uipanel(app.UIFigure);
            app.SelectsynthesisproblemPanel = uipanel(app.SimulationPanel);
            app.SelectsynthesisproblemPanel.Title = '1. Select synthesis problem';
            app.GridLayoutProblem = uigridlayout(app.SelectsynthesisproblemPanel);
            app.ProblemSwitch = uiswitch(app.GridLayoutProblem, 'slider');
            app.ProblemSwitch.Items = {'Regulation', 'Tracking'};
            app.ProblemSwitch.ValueChangedFcn = createCallbackFcn(app, @ProblemSwitchValueChanged, true);
            app.ProblemSwitch.Tag = 'Element';
            app.ProblemSwitch.Enable = 'off';
            app.ProblemSwitch.Tooltip = {'Switch between a regulation and tracking problem'};
            app.ProblemSwitch.Value = 'Regulation';
            app.ReferenceTrajectoryButton = uibutton(app.GridLayoutProblem, 'push');
            app.ReferenceTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceTrajectoryButtonPushed, true);
            app.ReferenceTrajectoryButton.Tag = 'Element';
            app.ReferenceTrajectoryButton.Enable = 'off';
            app.ReferenceTrajectoryButton.Tooltip = {'Open GUI for entering a reference trajectory'};
            app.ReferenceTrajectoryButton.Text = {'Reference '; 'Trajectory'};
            app.TabGroup = uitabgroup(app.SimulationPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.OpenloopsimulationTab = uitab(app.TabGroup);
            app.OpenloopsimulationTab.Title = 'Open loop simulation';
            app.OLTable = uitable(app.OpenloopsimulationTab);
            app.OLTable.ColumnName = '';
            app.OLTable.RowName = {};
            app.OLTable.ColumnEditable = true;
            app.OLTable.CellEditCallback = createCallbackFcn(app, @OLTableCellEdit, true);
            app.OLTable.Tooltip = {'Enter states by clicking in the tablecolumns'};
            app.OLTable.Enable = 'off';
            app.OLTable.Tag = 'Element';
            app.EntertheinitialstateLabel = uilabel(app.OpenloopsimulationTab);
            app.EntertheinitialstateLabel.Text = '2. Enter the initial state';
            app.OLSimulateButton = uibutton(app.OpenloopsimulationTab, 'push');
            app.OLSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @OLSimulateButtonPushed, true);
            app.OLSimulateButton.Tag = 'Element';
            app.OLSimulateButton.Enable = 'off';
            app.OLSimulateButton.Tooltip = {''};
            app.OLSimulateButton.Text = 'Simulate';
            app.OLNewSimulationButton = uibutton(app.OpenloopsimulationTab, 'push');
            app.OLNewSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @OLNewSimulationButtonPushed, true);
            app.OLNewSimulationButton.Tag = 'Element';
            app.OLNewSimulationButton.Enable = 'off';
            app.OLNewSimulationButton.Tooltip = {'Run a new simulation with a different configuration'};
            app.OLNewSimulationButton.Text = 'New simulation';
            app.OLUDropDown = uidropdown(app.OpenloopsimulationTab);
            app.OLUDropDown.Items = {'All Inputs'};
            app.OLUDropDown.ValueChangedFcn = createCallbackFcn(app, @OLUDropDownValueChanged, true);
            app.OLUDropDown.Tag = 'Element';
            app.OLUDropDown.Enable = 'off';
            app.OLUDropDown.Tooltip = {'Select which inputs should be displayed'};
            app.OLUDropDown.Value = 'All Inputs';
            app.OLXDropDown = uidropdown(app.OpenloopsimulationTab);
            app.OLXDropDown.Items = {'All States'};
            app.OLXDropDown.ValueChangedFcn = createCallbackFcn(app, @OLXDropDownValueChanged, true);
            app.OLXDropDown.Tag = 'Element';
            app.OLXDropDown.Enable = 'off';
            app.OLXDropDown.Tooltip = {'Select which states should be displayed'};
            app.OLXDropDown.Value = 'All States';
            app.OLStepSpinner = uispinner(app.OpenloopsimulationTab);
            app.OLStepSpinner.Limits = [0 Inf];
            app.OLStepSpinner.ValueDisplayFormat = '%.0f';
            app.OLStepSpinner.ValueChangedFcn = createCallbackFcn(app, @OLStepSpinnerValueChanged, true);
            app.OLStepSpinner.Tag = 'Element';
            app.OLStepSpinner.Editable = 'off';
            app.OLStepSpinner.Enable = 'off';
            app.OLStepSpinner.Tooltip = {''};
            app.OLMethodDropDown = uidropdown(app.OpenloopsimulationTab);
            app.OLMethodDropDown.Items = {'Time', 'State-Space'};
            app.OLMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OLMethodDropDownValueChanged, true);
            app.OLMethodDropDown.Tag = 'Element';
            app.OLMethodDropDown.Enable = 'off';
            app.OLMethodDropDown.Tooltip = {'Choose between time and state-space representation'};
            app.OLMethodDropDown.Value = 'Time';
            app.StartthesimulationLabel = uilabel(app.OpenloopsimulationTab);
            app.StartthesimulationLabel.Text = '3. Start the simulation';
            app.ChoosehowthestatesshouldbedisplayedLabel = uilabel(app.OpenloopsimulationTab);
            app.ChoosehowthestatesshouldbedisplayedLabel.Text = '4. Choose how the states should be displayed';
            app.ChoosewhichinputsstatesshouldbedisplayedLabel = uilabel(app.OpenloopsimulationTab);
            app.ChoosewhichinputsstatesshouldbedisplayedLabel.Text = '5. Choose which inputs/states should be displayed';
            app.GoonestepforwardbackwardintimeLabel = uilabel(app.OpenloopsimulationTab);
            app.GoonestepforwardbackwardintimeLabel.Text = '6+. Go one step forward/backward in time';
            app.ClosedloopsimlationTab = uitab(app.TabGroup);
            app.ClosedloopsimlationTab.Title = 'Closed loop simlation';
            app.EntertheinitialstateLabel_2 = uilabel(app.ClosedloopsimlationTab);
            app.EntertheinitialstateLabel_2.Text = '2. Enter the initial state';
            app.ChoosehowmuchdisturbanceshouldbeaddedLabel = uilabel(app.ClosedloopsimlationTab);
            app.ChoosehowmuchdisturbanceshouldbeaddedLabel.Text = '3. Choose how much disturbance should be added';
            app.ChoosehowthestatesshouldbedisplayedLabel_2 = uilabel(app.ClosedloopsimlationTab);
            app.ChoosehowthestatesshouldbedisplayedLabel_2.Text = '6. Choose how the states should be displayed';
            app.ChoosewhichinputsstatesshouldbedisplayedLabel_2 = uilabel(app.ClosedloopsimlationTab);
            app.ChoosewhichinputsstatesshouldbedisplayedLabel_2.Text = '7. Choose which inputs/states should be displayed';
            app.CLTable = uitable(app.ClosedloopsimlationTab);
            app.CLTable.ColumnName = '';
            app.CLTable.RowName = {};
            app.CLTable.ColumnEditable = true;
            app.CLTable.CellEditCallback = createCallbackFcn(app, @CLTableCellEdit, true);
            app.CLTable.Tooltip = {'Enter states by clicking in the tablecolumns'};
            app.CLTable.Enable = 'off';
            app.CLTable.Tag = 'Element';
            app.CLmaxDist = uieditfield(app.ClosedloopsimlationTab, 'numeric');
            app.CLmaxDist.ValueChangedFcn = createCallbackFcn(app, @CLmaxDistValueChanged, true);
            app.CLmaxDist.Tag = 'Element';
            app.CLmaxDist.Enable = 'off';
            app.CLmaxDist.Tooltip = {'Define a maximum value for the added disturbance (0 if there should be no disturbance)'};
            app.ChoosenumberofsimulationstepsLabel = uilabel(app.ClosedloopsimlationTab);
            app.ChoosenumberofsimulationstepsLabel.Text = '4. Choose number of simulation steps';
            app.CLNsimEditField = uieditfield(app.ClosedloopsimlationTab, 'numeric');
            app.CLNsimEditField.Limits = [1 Inf];
            app.CLNsimEditField.ValueDisplayFormat = '%.0f';
            app.CLNsimEditField.Tag = 'Element';
            app.CLNsimEditField.Enable = 'off';
            app.CLNsimEditField.Tooltip = {'Enter how many simulationsteps should be made'};
            app.CLNsimEditField.Value = 1;
            app.StartthesimulationLabel_2 = uilabel(app.ClosedloopsimlationTab);
            app.StartthesimulationLabel_2.Text = '5. Start the simulation';
            app.CLSimulateButton = uibutton(app.ClosedloopsimlationTab, 'push');
            app.CLSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @CLSimulateButtonPushed, true);
            app.CLSimulateButton.Tag = 'Element';
            app.CLSimulateButton.Enable = 'off';
            app.CLSimulateButton.Tooltip = {'Make closed loop simulation with initial states and disturbanceinterval'};
            app.CLSimulateButton.Text = 'Simulate';
            app.CLMethodDropDown = uidropdown(app.ClosedloopsimlationTab);
            app.CLMethodDropDown.Items = {'Time', 'State-Space'};
            app.CLMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CLMethodDropDownValueChanged, true);
            app.CLMethodDropDown.Tag = 'Element';
            app.CLMethodDropDown.Enable = 'off';
            app.CLMethodDropDown.Tooltip = {'Choose between time and state-space representation'};
            app.CLMethodDropDown.Value = 'Time';
            app.CLUDropDown = uidropdown(app.ClosedloopsimlationTab);
            app.CLUDropDown.Items = {'All Inputs'};
            app.CLUDropDown.ValueChangedFcn = createCallbackFcn(app, @CLUDropDownValueChanged, true);
            app.CLUDropDown.Tag = 'Element';
            app.CLUDropDown.Enable = 'off';
            app.CLUDropDown.Tooltip = {'Select which inputs should be displayed'};
            app.CLUDropDown.Value = 'All Inputs';
            app.CLXDropDown = uidropdown(app.ClosedloopsimlationTab);
            app.CLXDropDown.Items = {'All States'};
            app.CLXDropDown.ValueChangedFcn = createCallbackFcn(app, @CLXDropDownValueChanged, true);
            app.CLXDropDown.Tag = 'Element';
            app.CLXDropDown.Enable = 'off';
            app.CLXDropDown.Tooltip = {'Select which states should be displayed'};
            app.CLXDropDown.Value = 'All States';
            app.toLabel = uilabel(app.ClosedloopsimlationTab);
            app.toLabel.Enable = 'off';
            app.toLabel.Text = 'to';
            app.CLminDist = uieditfield(app.ClosedloopsimlationTab, 'numeric');
            app.CLminDist.ValueChangedFcn = createCallbackFcn(app, @CLminDistValueChanged, true);
            app.CLminDist.Tag = 'Element';
            app.CLminDist.Enable = 'off';
            app.CLminDist.Tooltip = {'Define a minimum value for the added disturbance (0 if there should be no disturbance)'};
            app.CLNewSimulationButton = uibutton(app.ClosedloopsimlationTab, 'push');
            app.CLNewSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @CLNewSimulationButtonPushed, true);
            app.CLNewSimulationButton.Tag = 'Element';
            app.CLNewSimulationButton.Enable = 'off';
            app.CLNewSimulationButton.Tooltip = {'Run a new simulation with a different configuration'};
            app.CLNewSimulationButton.Text = 'New simulation';
            app.PlantmodelmismatchTab = uitab(app.TabGroup);
            app.PlantmodelmismatchTab.Title = 'Plant-model-mismatch';
            app.EntertheinitialstateLabel_3 = uilabel(app.PlantmodelmismatchTab);
            app.EntertheinitialstateLabel_3.Text = '2. Enter the initial state';
            app.PMMTable = uitable(app.PlantmodelmismatchTab);
            app.PMMTable.ColumnName = '';
            app.PMMTable.RowName = {};
            app.PMMTable.ColumnEditable = true;
            app.PMMTable.CellEditCallback = createCallbackFcn(app, @PMMTableCellEdit, true);
            app.PMMTable.Tooltip = {'Enter states by clicking in the tablecolumns'};
            app.PMMTable.Enable = 'off';
            app.PMMTable.Tag = 'Element';
            app.StartthesimulationLabel_4 = uilabel(app.PlantmodelmismatchTab);
            app.StartthesimulationLabel_4.Text = '5. Start the simulation';
            app.PMMSimulateButton = uibutton(app.PlantmodelmismatchTab, 'push');
            app.PMMSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @PMMSimulateButtonPushed, true);
            app.PMMSimulateButton.Tag = 'Element';
            app.PMMSimulateButton.Enable = 'off';
            app.PMMSimulateButton.Tooltip = {'Make closed loop simulation with initial states and disturbanceinterval'};
            app.PMMSimulateButton.Text = 'Simulate';
            app.EnterplantmodelmismatchLabel = uilabel(app.PlantmodelmismatchTab);
            app.EnterplantmodelmismatchLabel.Text = '3. Enter plant-model-mismatch';
            app.PMMInputButton = uibutton(app.PlantmodelmismatchTab, 'push');
            app.PMMInputButton.ButtonPushedFcn = createCallbackFcn(app, @PMMInputButtonPushed, true);
            app.PMMInputButton.Tag = 'Element';
            app.PMMInputButton.Enable = 'off';
            app.PMMInputButton.Tooltip = {'Make closed loop simulation with initial states and disturbanceinterval'};
            app.PMMInputButton.Text = 'Enter plant-model-mismatch';
            app.PMMNewSimulationButton = uibutton(app.PlantmodelmismatchTab, 'push');
            app.PMMNewSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @PMMNewSimulationButtonPushed, true);
            app.PMMNewSimulationButton.Tag = 'Element';
            app.PMMNewSimulationButton.Enable = 'off';
            app.PMMNewSimulationButton.Tooltip = {'Run a new simulation with a different configuration'};
            app.PMMNewSimulationButton.Text = 'New simulation';
            app.ChoosenumberofsimulationstepsLabel_2 = uilabel(app.PlantmodelmismatchTab);
            app.ChoosenumberofsimulationstepsLabel_2.Text = '4. Choose number of simulation steps';
            app.PMMNsimEditField = uieditfield(app.PlantmodelmismatchTab, 'numeric');
            app.PMMNsimEditField.Limits = [1 Inf];
            app.PMMNsimEditField.ValueDisplayFormat = '%.0f';
            app.PMMNsimEditField.Tag = 'Element';
            app.PMMNsimEditField.Enable = 'off';
            app.PMMNsimEditField.Tooltip = {'Enter how many simulationsteps should be made'};
            app.PMMNsimEditField.Value = 1;
            app.ChoosethedisplaymethodLabel = uilabel(app.PlantmodelmismatchTab);
            app.ChoosethedisplaymethodLabel.Text = '6. Choose the display method';
            app.ChoosewhichinputsstatesshouldbedisplayedLabel_3 = uilabel(app.PlantmodelmismatchTab);
            app.ChoosewhichinputsstatesshouldbedisplayedLabel_3.Text = '7. Choose which inputs/states should be displayed';
            app.PMMMethodDropDown = uidropdown(app.PlantmodelmismatchTab);
            app.PMMMethodDropDown.Items = {'Time', 'State-Space', 'Cost'};
            app.PMMMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PMMMethodDropDownValueChanged, true);
            app.PMMMethodDropDown.Tag = 'Element';
            app.PMMMethodDropDown.Enable = 'off';
            app.PMMMethodDropDown.Tooltip = {'Choose between time and state-space representation'};
            app.PMMMethodDropDown.Value = 'Time';
            app.PMMUDropDown = uidropdown(app.PlantmodelmismatchTab);
            app.PMMUDropDown.Items = {'All Inputs'};
            app.PMMUDropDown.ValueChangedFcn = createCallbackFcn(app, @PMMUDropDownValueChanged, true);
            app.PMMUDropDown.Tag = 'Element';
            app.PMMUDropDown.Enable = 'off';
            app.PMMUDropDown.Tooltip = {'Select which inputs should be displayed'};
            app.PMMUDropDown.Value = 'All Inputs';
            app.PMMXDropDown = uidropdown(app.PlantmodelmismatchTab);
            app.PMMXDropDown.Items = {'All States'};
            app.PMMXDropDown.ValueChangedFcn = createCallbackFcn(app, @PMMXDropDownValueChanged, true);
            app.PMMXDropDown.Tag = 'Element';
            app.PMMXDropDown.Enable = 'off';
            app.PMMXDropDown.Tooltip = {'Select which states should be displayed'};
            app.PMMXDropDown.Value = 'All States';
            app.ExplicitsolutionTab = uitab(app.TabGroup);
            app.ExplicitsolutionTab.Title = 'Explicit solution';
            app.StartthesimulationLabel_3 = uilabel(app.ExplicitsolutionTab);
            app.StartthesimulationLabel_3.Text = '2. Start the simulation';
            app.SelectthedisplayedpartitionLabel = uilabel(app.ExplicitsolutionTab);
            app.SelectthedisplayedpartitionLabel.Text = '3. Select the displayed partition';
            app.InspectthecalculatedcoefficientsLabel = uilabel(app.ExplicitsolutionTab);
            app.InspectthecalculatedcoefficientsLabel.Text = '4. Inspect the calculated coefficients';
            app.EXRegionSimulateButton = uibutton(app.ExplicitsolutionTab, 'push');
            app.EXRegionSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @EXRegionSimulateButtonPushed, true);
            app.EXRegionSimulateButton.Tag = 'Element';
            app.EXRegionSimulateButton.Enable = 'off';
            app.EXRegionSimulateButton.Tooltip = {'Simulate explicit partitions in state space'};
            app.EXRegionSimulateButton.Text = 'Simulate';
            app.EXRegionSpinner = uispinner(app.ExplicitsolutionTab);
            app.EXRegionSpinner.Limits = [1 Inf];
            app.EXRegionSpinner.ValueDisplayFormat = '%.0f';
            app.EXRegionSpinner.ValueChangedFcn = createCallbackFcn(app, @EXRegionSpinnerValueChanged, true);
            app.EXRegionSpinner.Tag = 'Element';
            app.EXRegionSpinner.Editable = 'off';
            app.EXRegionSpinner.Enable = 'off';
            app.EXRegionSpinner.Tooltip = {'Select the partition that should be displayed and that the coefficients are shown for'};
            app.EXRegionSpinner.Value = 1;
            app.EXKTable = uitable(app.ExplicitsolutionTab);
            app.EXKTable.ColumnName = '';
            app.EXKTable.RowName = {};
            app.EXKTable.Tooltip = {'Coefficients of the matrix K for the shown equation'};
            app.EXKTable.Enable = 'off';
            app.EXKTable.Tag = 'Element';
            app.EXbTable = uitable(app.ExplicitsolutionTab);
            app.EXbTable.ColumnName = '';
            app.EXbTable.RowName = {};
            app.EXbTable.Tooltip = {'Coefficients of the matrix b for the shown equation'};
            app.EXbTable.Enable = 'off';
            app.EXbTable.Tag = 'Element';
            app.KLabel = uilabel(app.ExplicitsolutionTab);
            app.KLabel.Text = 'K';
            app.bLabel = uilabel(app.ExplicitsolutionTab);
            app.bLabel.Text = 'b';
            app.uKxbLabel = uilabel(app.ExplicitsolutionTab);
            app.uKxbLabel.Text = 'u = K * x + b';
            app.EXNewSimulationButton = uibutton(app.ExplicitsolutionTab, 'push');
            app.EXNewSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @EXNewSimulationButtonPushed, true);
            app.EXNewSimulationButton.Tag = 'Element';
            app.EXNewSimulationButton.Enable = 'off';
            app.EXNewSimulationButton.Tooltip = {'Run a new simulation with a different configuration'};
            app.EXNewSimulationButton.Text = 'New simulation';
            app.PlotPanel = uipanel(app.UIFigure);
            app.ModelPredictiveControlLabel = uilabel(app.PlotPanel);
            app.ModelPredictiveControlLabel.Text = 'Model Predictive Control';
            app.UIAxes1 = uiaxes(app.PlotPanel);
            title(app.UIAxes1, 'Inputs')
            xlabel(app.UIAxes1, 'k')
            ylabel(app.UIAxes1, '')
            app.UIAxes2 = uiaxes(app.PlotPanel);
            title(app.UIAxes2, 'States')
            xlabel(app.UIAxes2, 'k')
            ylabel(app.UIAxes2, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end