classdef ConstraintGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        InputTable            matlab.ui.control.Table
        StateTable            matlab.ui.control.Table
        AddconstraintsButton  matlab.ui.control.Button
        EnterinputandstateconstraintsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp           % Object from which the GUI was called        
        mpcObj               % Mpc Object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mpc)
            % Position GUI in the middle of the screen before it
            % becomes visible
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
                
            % Save AppObject of the calling app
            app.CallingApp = mainApp;
                
            % Save Mpc Object
            app.mpcObj = mpc;
            
            % Extract model
            model = mpc.model; 
                
            % Get Nu and Nx from the passed model
            Nu = model.nu;
            Nx = model.nx;               
            
            % Create cell for input data
            inputData = cell(Nu,1);   
            
            % Get minima and maxima
            minimumU = model.u.min;
            maximumU = model.u.max;
               
            % Fill input data with passed values
            for i=1:Nu
                input = char(append('u',string(i)));                  
                inputData(i,1:3) = {input,minimumU(i),maximumU(i)};
            end
            
            % Create cell for state data
            stateData = cell(Nx,1); 

            % Get minima and maxima
            minimumX = model.x.min;
            maximumX = model.x.max;    
            
            % Fill state data with passed values
            for j=1:Nx
                state = char(append('x',string(j)));
                stateData(j,1:3) = {state,minimumX(j),maximumX(j)};
            end            
            
            % Update table data
            app.InputTable.Data = inputData;
            app.StateTable.Data = stateData;
        end

        % Cell edit callback: InputTable
        function InputTableCellEdit(app, event)
            % Get row and column of the entered data
            index = event.Indices;
            row = index(1);
            column = index(2);
            
            % Get entered data
            data = event.NewData;

            
            switch(column)
                case 2 % Minimum   
                    % Check whether the input is valid  
                    if isreal(data) && isscalar(data) && ~isempty(data) && ~isnan(data) && data < app.mpcObj.model.u.max(row)
                        % Update data in model
                        app.mpcObj.model.u.min(row) = data;
                    else
                        % Get last valid value
                        app.InputTable.Data{row,2} = app.mpcObj.model.u.min(row);
                    end
                        
                case 3 % Maximum   
                    % Check whether the input is valid  
                    if isreal(data) && isscalar(data) && ~isempty(data) && ~isnan(data) && data > app.mpcObj.model.u.min(row)
                        % Update data in model
                        app.mpcObj.model.u.max(row) = data;
                    else
                        % Get last valid value
                        app.InputTable.Data{row,3} = app.mpcObj.model.u.max(row);
                    end           
            end
        end

        % Cell edit callback: StateTable
        function StateTableCellEdit(app, event)
            % Get row and column of the entered data
            index = event.Indices;
            row = index(1);
            column = index(2);
            
            % Get entered data
            data = event.NewData;

            
            switch(column)
                case 2 % Minimum   
                    % Check whether the input is valid  
                    if isreal(data) && isscalar(data) && ~isempty(data) && ~isnan(data) && data < app.mpcObj.model.x.max(row)
                        % Update data in model
                        app.mpcObj.model.x.min(row) = data;
                    else
                        % Get last valid value
                        app.StateTable.Data{row,2} = app.mpcObj.model.x.min(row);
                    end
                        
                case 3 % Maximum   
                    % Check whether the input is valid  
                    if isreal(data) && isscalar(data) && ~isempty(data) && ~isnan(data) && data > app.mpcObj.model.x.min(row)
                        % Update data in model
                        app.mpcObj.model.x.max(row) = data;
                    else
                        % Get last valid value
                        app.StateTable.Data{row,3} = app.mpcObj.model.x.max(row);
                    end           
            end
        end

        % Button pushed function: AddconstraintsButton
        function AddconstraintsButtonPushed(app, event)
            % Identify current window
            fig = app.UIFigure;            

            % Update model in the MainGUI
            updateMpc(app.CallingApp,app.mpcObj);  
            
            % Update numbers of constraints in the summary
            inputConstraints = sum(~isinf(app.mpcObj.model.u.min)) + sum(~isinf(app.mpcObj.model.u.max));
            stateConstraints = sum(~isinf(app.mpcObj.model.x.min)) + sum(~isinf(app.mpcObj.model.x.max));
            app.CallingApp.ConstraintsLabel.Text = append(string(inputConstraints),"/",string(stateConstraints));
            
            % Update enabled elements and lamps
            app.CallingApp.lastConfig(4:5) = 1;
            app.CallingApp.config = app.CallingApp.lastConfig;
            app.CallingApp.elements();            
                
            % Give feedback in info box and close the app, when the 
            % notice has been confirmed
            uialert(fig,'Constraints were updated successfully!','Info',"CloseFcn",@(h,e) delete(fig),"Icon",'success') 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable elements that were already enabled
            app.CallingApp.config = app.CallingApp.lastConfig;
            app.CallingApp.elements();
            
            % Close App
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InputTable = uitable(app.UIFigure);
            app.InputTable.ColumnName = {'Input'; 'Minimum'; 'Maximum'};
            app.InputTable.RowName = {};
            app.InputTable.ColumnEditable = [false true true];
            app.InputTable.CellEditCallback = createCallbackFcn(app, @InputTableCellEdit, true);
            app.InputTable.Tooltip = {'Enter minimum/maximum for any input by clicking in the corresponding field'};
            app.StateTable = uitable(app.UIFigure);
            app.StateTable.ColumnName = {'State'; 'Minimum'; 'Maximum'};
            app.StateTable.RowName = {};
            app.StateTable.ColumnEditable = [false true true];
            app.StateTable.CellEditCallback = createCallbackFcn(app, @StateTableCellEdit, true);
            app.StateTable.Tooltip = {'Enter minimum/maximum for any state by clicking in the corresponding field'};
            app.AddconstraintsButton = uibutton(app.UIFigure, 'push');
            app.AddconstraintsButton.ButtonPushedFcn = createCallbackFcn(app, @AddconstraintsButtonPushed, true);
            app.AddconstraintsButton.Tooltip = {''};
            app.AddconstraintsButton.Text = 'Add constraints';
            app.EnterinputandstateconstraintsLabel = uilabel(app.UIFigure);
            app.EnterinputandstateconstraintsLabel.Text = 'Enter input and state constraints';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConstraintGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end