classdef ModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ATable              matlab.ui.control.Table
        BTable              matlab.ui.control.Table
        CreatemodelButton   matlab.ui.control.Button
        MatrixBLabel        matlab.ui.control.Label
        MatrixALabel        matlab.ui.control.Label
        InputsSpinnerLabel  matlab.ui.control.Label
        InputSpinner        matlab.ui.control.Spinner
        StatesSpinnerLabel  matlab.ui.control.Label
        StateSpinner        matlab.ui.control.Spinner
        EntermatrixAandBfortheLTImodelxk1AxkBukLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        A = []               % Matrix A
        B = []               % Matrix B 
        
        Asaved = NaN(50);    % Matrix A with all saved entries
        Bsaved = NaN(50);    % Matrix B with all saved entries
        
        CallingApp           % Object from which the GUI was called
        
        mpcObj               % Mpc Object
        
        sim                  % Determines is simulation side is enabled
    end
    
    methods (Access = private)
        
        function updateTables(app,Nu,Nx)
            % Adjust the current table size and content for table A
            app.A = app.Asaved(1:Nx,1:Nx);
            app.ATable.Data = array2table(app.A);
            
            % Adjust the current table size and content for table B
            app.B = app.Bsaved(1:Nx,1:Nu);
            app.BTable.Data = array2table(app.B);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mpc, sim)
            % Position GUI in the middle of the screen before it
            % becomes visible
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
                
            % Save AppObject of the calling app
            app.CallingApp = mainApp;
            
            % Save Mpc Object            
            app.mpcObj = mpc; 

            % Extract model
            model = mpc.model;
            
            % Save sim bool
            app.sim = sim;
               
            % Check if a model already exists   
            if ~isempty(model.A) && ~isempty(model.B)               
                    
                % Get Nu and Nx from the passed model
                Nu = model.nu;
                Nx = model.nx;               
                    
                % Update spinners for inputs and states
                app.InputSpinner.Value = Nu;
                app.StateSpinner.Value = Nx;
                    
                % Get matrix A and B from the passed model
                app.Asaved(1:Nx,1:Nx) = model.A;
                app.Bsaved(1:Nx,1:Nu) = model.B;                
                    
            end
            
            % Update matrix representations
            InputSpinnerValueChanged(app)
            StateSpinnerValueChanged(app)
        end

        % Value changed function: InputSpinner
        function InputSpinnerValueChanged(app, event)
            % Get current number of inputs and states
            Nu = app.InputSpinner.Value;
            Nx = app.StateSpinner.Value;
            
            % Adjust current table size and content
            updateTables(app,Nu,Nx)
        end

        % Value changed function: StateSpinner
        function StateSpinnerValueChanged(app, event)
            % Get current number of inputs and states
            Nu = app.InputSpinner.Value;
            Nx = app.StateSpinner.Value;
            
            % Adjust current table size and content
            updateTables(app,Nu,Nx)
        end

        % Cell edit callback: ATable
        function ATableCellEdit(app, event)
            % Get row and column of the entered data
            index = event.Indices;
            row = index(1);
            column = index(2);
            
            % Get entered data
            data = event.NewData;
                
            % Check whether the input is valid
            if ~isnan(data) && ~isinf(data) && isscalar(data) && isreal(data) && ~isempty(data)
                
                % Write input into the matrix
                app.Asaved(row,column) = data;
                
                % Get current number of states
                Nx = app.StateSpinner.Value;
                
                % Update table content
                app.A = app.Asaved(1:Nx,1:Nx);
                app.ATable.Data = array2table(app.A);
            else
                % Get last valid value
                app.ATable.Data(row,column) = {app.A(row,column)};                  
            end   
        end

        % Cell edit callback: BTable
        function BTableCellEdit(app, event)
            % Get row and column of the entered data
            index = event.Indices;
            row = index(1);
            column = index(2);
            
            % Get entered data
            data = event.NewData;
                
            % Check whether the input is valid
            if ~isnan(data) && ~isinf(data) && isscalar(data) && isreal(data) && ~isempty(data)
                
                % Write input into the matrix
                app.Bsaved(row,column) = data;
                
                % Get current number of inputs and states
                Nu = app.InputSpinner.Value;
                Nx = app.StateSpinner.Value;
                
                % Update table content
                app.B = app.Bsaved(1:Nx,1:Nu);
                app.BTable.Data = array2table(app.B);
            else
                % Get last valid value
                app.BTable.Data(row,column) = {app.B(row,column)};                  
            end 
        end

        % Button pushed function: CreatemodelButton
        function CreatemodelButtonPushed(app, event)
            % Identify current window
            fig = app.UIFigure;
            
            % Check whether all fields have been filled in
            if all(all(~isnan(app.A))) && all(all(~isnan(app.B)))                                                                                                        
                % Update enabled elements
                app.CallingApp.config = [1,1,1,0,0,0,0,0,0,0];
                
                % Check if there's already an mpc object
                if ~isempty(app.mpcObj.model.A)                     

                    % Get values of old mpc
                    N = app.mpcObj.N;
                    uMin = app.mpcObj.model.u.min;
                    uMax = app.mpcObj.model.u.max;
                    xMin = app.mpcObj.model.x.min;
                    xMax = app.mpcObj.model.x.max;
                    nu = app.mpcObj.nu;
                    nx = app.mpcObj.nx;
                    
                    try
                        % Get penaltyclass
                        selectedFunction = class(app.mpcObj.model.u.penalty);
                        
                        % Try to get input and state penalties
                        uWeight = app.mpcObj.model.u.penalty.weight;
                        xWeight = app.mpcObj.model.x.penalty.weight;                       
                    end
                    
                    try
                        % Try to get terminal Set and Penalty
                        app.mpcObj.model.x.terminalSet;
                        terminal = 1;
                    catch
                        terminal = 0;
                    end
                
                end
                
                % Update matrices of the system model
                model = LTISystem('A', app.A, 'B', app.B);
                mpc = MPCController(model,1);                

                % Try to take over old data if size still matches
                try
                    if nu == app.InputSpinner.Value && nx == app.StateSpinner.Value
    
                        % Constraints
                        mpc.model.u.min = uMin;
                        mpc.model.u.max = uMax;
                        mpc.model.x.min = xMin;
                        mpc.model.x.max = xMax;
    
                        % Penalties
                        try               

                            % Set penalties with quadratic function
                            if selectedFunction == "QuadFunction"
                              mpc.model.u.penalty = QuadFunction(uWeight);
                              mpc.model.x.penalty = QuadFunction(xWeight);
                                                        
                            % Set penalties with one norm function
                            elseif selectedFunction == "OneNormFunction"
                                mpc.model.u.penalty = OneNormFunction(uWeight);
                                mpc.model.x.penalty = OneNormFunction(xWeight);
                                
                            % Set penalties with inf norm function
                            elseif selectedFunction == "InfNormFunction"
                                mpc.model.u.penalty = InfNormFunction(uWeight);
                                mpc.model.x.penalty = InfNormFunction(xWeight);
                            end
                        
                            
                        end
                        
                        % Terminal set and penalty
                        if terminal == 1  
                            % Calculate terminal set and penalty
                            Tset = mpc.model.LQRSet;
                            PN = mpc.model.LQRPenalty;                            
                            
                            % Add terminal set and terminal penalty
                            mpc.model.x.with('terminalSet');
                            mpc.model.x.terminalSet = Tset;
                            mpc.model.x.with('terminalPenalty');
                            mpc.model.x.terminalPenalty = PN; 
                            
                            % Set radiobutton group
                            app.CallingApp.ComputetsetandtpenaltyButton.Value = 1;
                            
                        end
                        
                        % Prediction horizon
                        mpc.N = N;  
                        
                        % Call last configuration
                        app.CallingApp.config = app.CallingApp.lastConfig;                        
                        
                    end
                end
                
                % Enable starting simulation elements
                if app.sim
                    app.CallingApp.ProblemSwitch.Enable = "on";
                    app.CallingApp.OLTable.Enable = "on";
                    app.CallingApp.CLTable.Enable = "on";
                    app.CallingApp.PMMTable.Enable = "on";
                    app.CallingApp.EXRegionSimulateButton.Enable = "on";
                end
                    
                % Update model in the MainGUI
                updateMpc(app.CallingApp,mpc); 

                % Get number of inputs and states
                Nu = mpc.model.nu;
                Nx = mpc.model.nx;
                
                % Update numbers in the summary
                app.CallingApp.NuNxLabel.Text = append(string(Nu),"/",string(Nx));

                % Create cell with state names and inital values
                stateData = cell(1,Nx);                    
                for i=1:Nx
                    stateData(1,i) = {char(append('x',string(i),'(0)'))};
                    stateData(2,i) = {NaN};
                end                                                                                       
                                
                % Update Columnnames
                app.CallingApp.OLTable.ColumnName = stateData(1,:);
                app.CallingApp.CLTable.ColumnName = stateData(1,:);
                app.CallingApp.PMMTable.ColumnName = stateData(1,:);
            
                % Update table content
                app.CallingApp.OLTable.Data = stateData(2,:);
                app.CallingApp.CLTable.Data = stateData(2,:);
                app.CallingApp.PMMTable.Data = stateData(2,:);
                
                % Initialize arrays with NaN
                app.CallingApp.OLXInitial = NaN(1,Nx);
                app.CallingApp.OLX = NaN(1,Nx);
                app.CallingApp.CLXInitial = NaN(1,Nx); 
                app.CallingApp.PMMXInitial = NaN(1,Nx);
                
                % Fill dropdown menus with inputs
                inputSelection(1) = "All Inputs";
                for i=1:Nu
                    inputSelection(i+1) = append('Input ',string(i));
                end   
                app.CallingApp.OLUDropDown.Items = inputSelection;
                app.CallingApp.CLUDropDown.Items = inputSelection;
                app.CallingApp.PMMUDropDown.Items = inputSelection;
                
                % Connect inputs with numbers 0:Nu
                app.CallingApp.OLUDropDown.ItemsData = 0:Nu;  
                app.CallingApp.CLUDropDown.ItemsData = 0:Nu;
                app.CallingApp.PMMUDropDown.ItemsData = 0:Nu;
                
                % Fill dropdown menus with states
                stateSelection(1) = "All States";
                for j=1:Nx
                    stateSelection(j+1) = append('State ',string(j));
                end   
                app.CallingApp.OLXDropDown.Items = stateSelection;
                app.CallingApp.CLXDropDown.Items = stateSelection;
                app.CallingApp.PMMXDropDown.Items = stateSelection;
                
                % Connect states with numbers 0:Nx
                app.CallingApp.OLXDropDown.ItemsData = 0:Nx;
                app.CallingApp.CLXDropDown.ItemsData = 0:Nx; 
                app.CallingApp.PMMXDropDown.ItemsData = 0:Nx;

                % Call function for updating the enabled elements
                app.CallingApp.elements();   
                
                % Reset simulation side
                resetSim(app.CallingApp) 
                
                % Give feedback in info box and close the app, when the 
                % notice has been confirmed
                uialert(fig,'Model was created successfully!','Info',"CloseFcn",@(h,e) delete(fig),"Icon",'success') 

            else
                % Alert user to incomplete input
                message = sprintf('Please fill out all fields or cancel the entry!');
                uialert(app.UIFigure,message,'Error','Icon','error');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable elements that were already enabled
            app.CallingApp.config = app.CallingApp.lastConfig;
            app.CallingApp.elements();
            
            % Close App
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ATable = uitable(app.UIFigure);
            app.ATable.ColumnName = '';
            app.ATable.RowName = {};
            app.ATable.ColumnEditable = true;
            app.ATable.CellEditCallback = createCallbackFcn(app, @ATableCellEdit, true);
            app.ATable.Tooltip = {'Enter the coefficients by clicking in matrix'};
            app.BTable = uitable(app.UIFigure);
            app.BTable.ColumnName = '';
            app.BTable.RowName = {};
            app.BTable.ColumnEditable = true;
            app.BTable.CellEditCallback = createCallbackFcn(app, @BTableCellEdit, true);
            app.BTable.Tooltip = {'Enter the coefficients by clicking in matrix'};
            app.CreatemodelButton = uibutton(app.UIFigure, 'push');
            app.CreatemodelButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemodelButtonPushed, true);
            app.CreatemodelButton.Tooltip = {'Create systemmodel with matrices A and B'};
            app.CreatemodelButton.Text = 'Create model';
            app.MatrixBLabel = uilabel(app.UIFigure);
            app.MatrixBLabel.Text = 'Matrix B';
            app.MatrixALabel = uilabel(app.UIFigure);
            app.MatrixALabel.Text = 'Matrix A';
            app.InputsSpinnerLabel = uilabel(app.UIFigure);
            app.InputsSpinnerLabel.Text = 'Inputs';
            app.InputSpinner = uispinner(app.UIFigure);
            app.InputSpinner.Limits = [1 50];
            app.InputSpinner.ValueChangedFcn = createCallbackFcn(app, @InputSpinnerValueChanged, true);
            app.InputSpinner.Tooltip = {'Select the number of inputs for your model'};
            app.InputSpinner.Value = 1;
            app.StatesSpinnerLabel = uilabel(app.UIFigure);
            app.StatesSpinnerLabel.Text = 'States';
            app.StateSpinner = uispinner(app.UIFigure);
            app.StateSpinner.Limits = [1 50];
            app.StateSpinner.ValueChangedFcn = createCallbackFcn(app, @StateSpinnerValueChanged, true);
            app.StateSpinner.Tooltip = {'Select the number of states for your model'};
            app.StateSpinner.Value = 1;
            app.EntermatrixAandBfortheLTImodelxk1AxkBukLabel = uilabel(app.UIFigure);
            app.EntermatrixAandBfortheLTImodelxk1AxkBukLabel.Tooltip = {'x(k+1) = Ax(k) + Bu(k)'};
            app.EntermatrixAandBfortheLTImodelxk1AxkBukLabel.Text = 'Enter matrix A and B for the LTI model x(k+1) = Ax(k) + Bu(k)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end