classdef PenaltyGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        InputpenaltyRLabel   matlab.ui.control.Label
        StatepenaltyQLabel   matlab.ui.control.Label
        InputTable           matlab.ui.control.Table
        StateTable           matlab.ui.control.Table
        AddpenaltiesButton   matlab.ui.control.Button
        FunctionButtonGroup  matlab.ui.container.ButtonGroup
        QuadraticButton      matlab.ui.control.RadioButton
        OneNormButton        matlab.ui.control.RadioButton
        InfNormButton        matlab.ui.control.RadioButton
        EnterinputandstatepenaltiesLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp           % Object from which the GUI was called        
        mpcObj               % Mpc Object
        R                    % Matrix R (Input)
        Q                    % Matrix Q (State)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mpc)
            % Position GUI in the middle of the screen before it
            % becomes visible
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
              
            % Save AppObject of the calling app
            app.CallingApp = mainApp;
                
            % Save Mpc Object
            app.mpcObj = mpc;
            
            % Extract model
            model = mpc.model;             
                
            % Check if penalties were already defined
            if ~isempty(model.u.penalty) && ~isempty(model.x.penalty)                

                % Get matrix R and Q from passed model
                app.R = model.u.penalty.weight;
                app.Q = model.x.penalty.weight;
                
                % Get Type of penalty
                type = class(model.u.penalty);
                
                % Check box for penalty type
                switch(type)
                    case "QuadFunction"
                        app.QuadraticButton.Value = 1;
                    case "OneNormFunction"
                        app.OneNormButton.Value = 1;
                    case "InfNormFunction"
                        app.InfNormButton.Value = 1;
                end
                                
            else
                
                % Get Nu and Nx from passed model
                Nu = model.nu;
                Nx = model.nx;
                
                % Create identity matrices as standard
                app.R = eye(Nu); 
                app.Q = eye(Nx);
            end
            
            
            % Adjust the current table size and content for table R and Q
            app.InputTable.Data = array2table(app.R);
            app.StateTable.Data = array2table(app.Q);  
        end

        % Cell edit callback: InputTable
        function InputTableCellEdit(app, event)
            % Get row and column of the entered data
            index = event.Indices;
            row = index(1);
            column = index(2);
            
            % Get entered data
            data = event.NewData;
                                
            % Check whether the input is valid 
            if isreal(data) && isscalar(data) && ~isempty(data) && ~isnan(data)
                % Update data in model
                app.R(row,column) = data;
            else
                % Get last valid value
                app.InputTable.Data(row,column) = {app.R(row,column)};
            end
        end

        % Cell edit callback: StateTable
        function StateTableCellEdit(app, event)
            % Get row and column of the entered data
            index = event.Indices;
            row = index(1);
            column = index(2);
            
            % Get entered data
            data = event.NewData;
                                
            % Check whether the input is valid 
            if isreal(data) && isscalar(data) && ~isempty(data) && ~isnan(data)
                % Update data in model
                app.Q(row,column) = data;
            else
                % Get last valid value
                app.StateTable.Data(row,column) = {app.Q(row,column)};
            end
        end

        % Button pushed function: AddpenaltiesButton
        function AddpenaltiesButtonPushed(app, event)
            % Identify current window
            fig = app.UIFigure;            

            % Try setting penalties
            try 
                % Get selected function
                selectedFunction = app.FunctionButtonGroup.SelectedObject.Text;
                
                % Set penalties with quadratic function
                if selectedFunction == "Quadratic"
                    app.mpcObj.model.u.penalty = QuadFunction(app.R);
                    app.mpcObj.model.x.penalty = QuadFunction(app.Q);
                
                % Set penalties with one norm function
                elseif selectedFunction == "One Norm"
                    app.mpcObj.model.u.penalty = OneNormFunction(app.R);
                    app.mpcObj.model.x.penalty = OneNormFunction(app.Q);
                    
                % Set penalties with inf norm function
                elseif selectedFunction == "Inf Norm"
                    app.mpcObj.model.u.penalty = InfNormFunction(app.R);
                    app.mpcObj.model.x.penatly = InfNormFunction(app.Q);
                end
                
                % Update model in the MainGUI
                updateMpc(app.CallingApp,app.mpcObj); 
                
                % Update entry in the summary
                app.CallingApp.PenaltiesLabel.Text = selectedFunction;
                
                % Update enabled elements and lamps
                app.CallingApp.lastConfig(6:9) = 1;
                app.CallingApp.config = app.CallingApp.lastConfig;
                app.CallingApp.elements();  
                
                % Give feedback in info box and close the app, when the 
                % notice has been confirmed
                uialert(fig,'Penalties set successfully!','Info',"CloseFcn",@(h,e) delete(fig),"Icon",'success')
            catch ME
                uialert(fig,'Penalties could not be set!','Error','Icon','error');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable elements that were already enabled
            app.CallingApp.config = app.CallingApp.lastConfig;
            app.CallingApp.elements();
            
            % Close App
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InputpenaltyRLabel = uilabel(app.UIFigure);
            app.InputpenaltyRLabel.Text = 'Input penalty - R';
            app.StatepenaltyQLabel = uilabel(app.UIFigure);
            app.StatepenaltyQLabel.Text = 'State penalty - Q';
            app.InputTable = uitable(app.UIFigure);
            app.InputTable.ColumnName = '';
            app.InputTable.RowName = {};
            app.InputTable.ColumnEditable = true;
            app.InputTable.CellEditCallback = createCallbackFcn(app, @InputTableCellEdit, true);
            app.InputTable.Tooltip = {'Enter the coefficients for the matrix R (input penalty) by clicking in the corresponding field'};
            app.StateTable = uitable(app.UIFigure);
            app.StateTable.ColumnName = '';
            app.StateTable.RowName = {};
            app.StateTable.ColumnEditable = true;
            app.StateTable.CellEditCallback = createCallbackFcn(app, @StateTableCellEdit, true);
            app.StateTable.Tooltip = {'Enter the coefficients for the matrix Q (state penalty) by clicking in the corresponding field'};
            app.AddpenaltiesButton = uibutton(app.UIFigure, 'push');
            app.AddpenaltiesButton.ButtonPushedFcn = createCallbackFcn(app, @AddpenaltiesButtonPushed, true);
            app.AddpenaltiesButton.Tooltip = {''};
            app.AddpenaltiesButton.Text = 'Add penalties';
            app.FunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.FunctionButtonGroup.Tooltip = {'Choose which function should be used in the optimization problem'};
            app.FunctionButtonGroup.Title = 'Function';
            app.QuadraticButton = uiradiobutton(app.FunctionButtonGroup);
            app.QuadraticButton.Text = 'Quadratic';
            app.QuadraticButton.Value = true;
            app.OneNormButton = uiradiobutton(app.FunctionButtonGroup);
            app.OneNormButton.Text = 'One Norm';
            app.InfNormButton = uiradiobutton(app.FunctionButtonGroup);
            app.InfNormButton.Text = 'Inf Norm';
            app.EnterinputandstatepenaltiesLabel = uilabel(app.UIFigure);
            app.EnterinputandstatepenaltiesLabel.Text = {'Enter input and state penalties'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PenaltyGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end