classdef PlantGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ATableModel             matlab.ui.control.Table
        BTableModel             matlab.ui.control.Table
        ATablePlant             matlab.ui.control.Table
        BTablePlant             matlab.ui.control.Table
        SystemmodelLabel        matlab.ui.control.Label
        PlantmodelLabel         matlab.ui.control.Label
        MatrixALabel            matlab.ui.control.Label
        MatrixBLabel            matlab.ui.control.Label
        MatrixALabel_2          matlab.ui.control.Label
        MatrixBLabel_2          matlab.ui.control.Label
        DefinePlantmodelButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        A = []          % Matrix A of the plantmodel
        B = []          % Matrix B of the plantmodel
        
        CallingApp      % Object from which the GUI was called    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mpc, PlantModel)
            % Position GUI in the middle of the screen before it
            % becomes visible
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
                
            % Save appObject of the calling app
            app.CallingApp = mainApp;           
            
            % Write matrix A and B from passed mpc into the tables 
            app.ATableModel.Data = array2table(mpc.model.A);            
            app.BTableModel.Data = array2table(mpc.model.B);
            
            
            % Check if plantmodel was defined already
            if isempty(PlantModel)
                % Get matrices of systemmodel
                matrixA = mpc.model.A;
                matrixB = mpc.model.B;
            else
                % Get defined plantmodel
                matrixA = PlantModel.A;
                matrixB = PlantModel.B;
            end
            
            % Display matrices
            app.ATablePlant.Data = array2table(matrixA);
            app.BTablePlant.Data = array2table(matrixB);
            
            % Save matrices
            app.A = matrixA;
            app.B = matrixB;
        end

        % Cell edit callback: ATablePlant
        function ATablePlantCellEdit(app, event)
            % Get row and columns of entered data
            indices = event.Indices;
            row = indices(1);
            column = indices(2);
            
            % Get entered data
            data = event.NewData;
            
            % Check whether input is valid
            if ~isnan(data) && ~isinf(data) && isscalar(data) && isreal(data) && ~isempty(data)
                % Write entered data into matrix A
                app.A(row,column) = data;
                app.ATablePlant.Data = array2table(app.A);
            else
                % Show last valid value if data is invalid
                app.ATablePlant.Data(row,column) = {app.A(row,column)};                  
            end
        end

        % Cell edit callback: BTablePlant
        function BTablePlantCellEdit(app, event)
            % Get row and columns of entered data
            indices = event.Indices;
            row = indices(1);
            column = indices(2);
            
            % Get entered data
            data = event.NewData;
            
            % Check whether input is valid
            if ~isnan(data) && ~isinf(data) && isscalar(data) && isreal(data) && ~isempty(data)
                % Write entered data into matrix A
                app.B(row,column) = data;
                app.BTablePlant.Data = array2table(app.B);
            else
                % Show last valid value if data is invalid
                app.BTablePlant.Data(row,column) = {app.B(row,column)};                  
            end
        end

        % Button pushed function: DefinePlantmodelButton
        function DefinePlantmodelButtonPushed(app, event)
            % Identify current window
            fig = app.UIFigure;
            
            % Update matrices of the CL plantmodel
            plantModel = LTISystem('A', app.A, 'B', app.B);
                
            % Update plantmodel in the main app
            app.CallingApp.PlantModel = plantModel;   
            
            % Activate next button
            app.CallingApp.PMMSimulateButton.Enable = 'on';

            % Inform user of successfull update
            uialert(fig,"Plantmodel was updated successfully!","Info","CloseFcn",@(h,e) delete(fig),"Icon",'success') 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close App
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ATableModel = uitable(app.UIFigure);
            app.ATableModel.ColumnName = '';
            app.ATableModel.RowName = {};
            app.ATableModel.Tooltip = {'Matrix A of the defined systemmodel'};
            app.BTableModel = uitable(app.UIFigure);
            app.BTableModel.ColumnName = '';
            app.BTableModel.RowName = {};
            app.BTableModel.Tooltip = {'Matrix B of the defined systemmodel'};
            app.ATablePlant = uitable(app.UIFigure);
            app.ATablePlant.ColumnName = '';
            app.ATablePlant.RowName = {};
            app.ATablePlant.ColumnEditable = true;
            app.ATablePlant.CellEditCallback = createCallbackFcn(app, @ATablePlantCellEdit, true);
            app.ATablePlant.Tooltip = {'Define coefficients of matrix A of the plantmodel'};
            app.BTablePlant = uitable(app.UIFigure);
            app.BTablePlant.ColumnName = '';
            app.BTablePlant.RowName = {};
            app.BTablePlant.ColumnEditable = true;
            app.BTablePlant.CellEditCallback = createCallbackFcn(app, @BTablePlantCellEdit, true);
            app.BTablePlant.Tooltip = {'Define coefficients of matrix B of the plantmodel'};
            app.SystemmodelLabel = uilabel(app.UIFigure);
            app.SystemmodelLabel.Text = 'Systemmodel';
            app.PlantmodelLabel = uilabel(app.UIFigure);
            app.PlantmodelLabel.Text = 'Plantmodel';
            app.MatrixALabel = uilabel(app.UIFigure);
            app.MatrixALabel.Text = 'Matrix A';
            app.MatrixBLabel = uilabel(app.UIFigure);
            app.MatrixBLabel.Text = 'Matrix B';
            app.MatrixALabel_2 = uilabel(app.UIFigure);
            app.MatrixALabel_2.Text = 'Matrix A';
            app.MatrixBLabel_2 = uilabel(app.UIFigure);
            app.MatrixBLabel_2.Text = 'Matrix B';
            app.DefinePlantmodelButton = uibutton(app.UIFigure, 'push');
            app.DefinePlantmodelButton.ButtonPushedFcn = createCallbackFcn(app, @DefinePlantmodelButtonPushed, true);
            app.DefinePlantmodelButton.Tooltip = {'Define plantmodel as plant-model-mismatch'};
            app.DefinePlantmodelButton.Text = 'Define Plantmodel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlantGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end