classdef ReferenceGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        FinishedButton                  matlab.ui.control.Button
        NumberofsteadystatesSpinnerLabel  matlab.ui.control.Label
        NumberofsteadystatesSpinner     matlab.ui.control.Spinner
        DefinereferencetrajectoryLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp           % Object from which the GUI was called        
        mpcObj               % Mpc Object      
        trajectories         % Passed reference trajectories
    end
    
    methods (Access = private)
        
        function plotReference(app)
            % Plot reference trajectories is all fields have been filled
            if all(all(~isnan(cell2mat(app.trajectories(:,2:end)))))
                %Generate trajectories with entered values
                xRef = [];
                profiles = app.NumberofsteadystatesSpinner.Value;
                
                for i=1:profiles
                    xRefNew = repmat(cell2mat(app.trajectories(i,3:end))',1,app.trajectories{i,2});
                    xRef = [xRef xRefNew];
                end
                
                % Duplicate last values for better display
                xRef(:,end+1) = xRef(:,end);
                
                % Plot trajectories and set xticks
                stairs(app.UIAxes,[0:1:(length(xRef)-1)],xRef','LineWidth',2)
                xticks(app.UIAxes,[-1:1:(length(xRef)+1)])
                
                % Set x-limits
                xlim(app.UIAxes,[-1 (length(xRef)+1)])
                
                % Set y-limits
                minimum = min(min(xRef));
                maximum = max(max(xRef));
                ylim(app.UIAxes,[minimum-1 maximum+1]);
                
                % Set legend names
                states = app.mpcObj.nx;
                for i=1:states
                    legNames(i) = append("Reference ",string(i));                            
                end
                legend(app.UIAxes,legNames);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mpc, trajectories)
            % Position GUI in the middle of the screen before it
            % becomes visible
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
                
            % Save AppObject of the calling app
            app.CallingApp = mainApp;
                
            % Save Mpc Object
            app.mpcObj = mpc;
            
            % Save reference trajectories
            app.trajectories = trajectories;
            
            % Get number of states
            Nx = mpc.model.nx;
            
            % Create cellarray for table content
            if isempty(trajectories)
                app.trajectories = cell(1,Nx+2);
                app.trajectories(1,2:end) = {nan};
            end

            % Generate column names for all states
            states = cell(1,Nx);
            for j=1:Nx
                states(1,j) = {append("x",string(j))};
            end

            % Rename columns
            app.UITable.ColumnName = ["Profile","Number of steps",states];

            % Get tablesize
            [rows,columns] = size(app.trajectories);
            
            % Update spinnervalue
            app.NumberofsteadystatesSpinner.Value = rows;
                
            % Define editable status of columns
            columndEdit(1) = false;
            columndEdit(2:columns+1) = true;
            app.UITable.ColumnEditable = columndEdit;
                
            % Fill cellarray with data
            for k=1:rows
                profile = {char(append("Profile ",string(k)))};
                app.trajectories(k,:) = [profile,app.trajectories(k,2:end)];
            end
            
            % Update table content
            app.UITable.Data = app.trajectories;
            
            % Plot references if possible           
            plotReference(app)
        end

        % Value changed function: NumberofsteadystatesSpinner
        function NumberofsteadystatesSpinnerValueChanged(app, event)
            % Get current value
            profiles = app.NumberofsteadystatesSpinner.Value;
            
            % Get current rows
            [rows,~] = size(app.trajectories);
            
            % Determine if a row has to be deleted or addes
            if profiles < rows
                % Delete one row
                app.trajectories = app.trajectories(1:profiles,:);
            elseif profiles > rows
                % Add new row
                app.trajectories{profiles,1} = char(append("Profile ",string(profiles)));
                app.trajectories(profiles,2:end) = {nan};
            end
            
            % Update table content
            app.UITable.Data = app.trajectories;
            
            % Plot reference if possible
            plotReference(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Get row and columns of input data
            index = event.Indices;
            row = index(1);
            column = index(2);
            
            % Get input data
            data = event.NewData;
            
            % Update data depending on the column
            if column == 2 % Steps columns
                % Check if input is valud
                if isreal(data) && isscalar(data) && ~isempty(data) && ~isnan(data) && round(data) == data && data > 0
                    % Update value 
                    app.trajectories(row,2) = {data};
                else
                    % Get last valid value if input is invalid
                    app.UITable.Data(row,2) = app.trajectories(row,2);
                end
                
            elseif column > 2 % Statevalues columns
                % Check if input is valid
                if isreal(data) && isscalar(data) && ~isempty(data) && ~isnan(data) && data >= app.mpcObj.model.x.min(column-2) && data <= app.mpcObj.model.x.max(column-2)
                    % Update value
                    app.trajectories(row,column) = {data};
                else
                    % Get last valid value if input is invalid
                    app.UITable.Data(row,column) = app.trajectories(row,column);
                end  
            end    
            
            % Plot references if possible
            plotReference(app)
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            % Check if all fields have been filled
            if all(all(~isnan(cell2mat(app.trajectories(:,2:end)))))
                %Generate trajectories with entered values
                xRef = [];
                profiles = app.NumberofsteadystatesSpinner.Value;
                
                for i=1:profiles
                    xRefNew = repmat(cell2mat(app.trajectories(i,3:end))',1,app.trajectories{i,2});
                    xRef = [xRef xRefNew];
                end
                
                % Update references in main app
                app.CallingApp.xRef = xRef;
                
                % Update table content of trajectories in main app
                app.CallingApp.xRefTableContent = app.trajectories;
                
                % Update timesteps for CL simulation
                app.CallingApp.CLNsimEditField.Value = length(xRef);
                app.CallingApp.PMMNsimEditField.Value = length(xRef);
                
                % Identify current window
                fig = app.UIFigure;
                
                % Give feedback in info box and close the app, when the 
                % notice has been confirmed
                uialert(fig,'References were entered successfully!','Info',"CloseFcn",@(h,e) delete(fig),"Icon",'success') 
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Turn buttons back on
            
                
            % Close App
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Tooltip = {'Enter the statevalues and how many timesteps these values should be applied. The defined references are shown in the plot if all fields have been filled'};
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Tooltip = {''};
            app.FinishedButton.Text = {'Finished'; ''};
            app.NumberofsteadystatesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofsteadystatesSpinnerLabel.Text = 'Number of steady states';
            app.NumberofsteadystatesSpinner = uispinner(app.UIFigure);
            app.NumberofsteadystatesSpinner.Limits = [1 Inf];
            app.NumberofsteadystatesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofsteadystatesSpinnerValueChanged, true);
            app.NumberofsteadystatesSpinner.Tooltip = {'Select how many different statevalues should be in the trajectory'};
            app.NumberofsteadystatesSpinner.Value = 1;
            app.DefinereferencetrajectoryLabel = uilabel(app.UIFigure);
            app.DefinereferencetrajectoryLabel.Text = 'Define reference trajectory';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Defined references')
            xlabel(app.UIAxes, 'k')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReferenceGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end